classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DviewMenu                     matlab.ui.container.Menu
        InfoMenu                      matlab.ui.container.Menu
        Menu                          matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
        Panel                         matlab.ui.container.Panel
        VuespectateurButton           matlab.ui.control.Button
        tempsrestantEditField         matlab.ui.control.EditField
        tempsrestantEditFieldLabel    matlab.ui.control.Label
        tempscouleEditField           matlab.ui.control.EditField
        tempscoulEditFieldLabel       matlab.ui.control.Label
        DronesperdusEditField         matlab.ui.control.NumericEditField
        DronesperdusEditFieldLabel    matlab.ui.control.Label
        DronesrestantsEditField       matlab.ui.control.NumericEditField
        DronesrestantsEditFieldLabel  matlab.ui.control.Label
        FPSEditField                  matlab.ui.control.NumericEditField
        FPSEditFieldLabel             matlab.ui.control.Label
        CoorDropDown                  matlab.ui.control.DropDown
        CoorDropDownLabel             matlab.ui.control.Label
        TargetDropDown                matlab.ui.control.DropDown
        TargetDropDownLabel           matlab.ui.control.Label
        DronePosField                 matlab.ui.control.NumericEditField
        EditFieldLabel                matlab.ui.control.Label
        Slider                        matlab.ui.control.Slider
        SliderLabel                   matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        RunButton                     matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        DviewTab                      matlab.ui.container.Tab
        UIAxes3D                      matlab.ui.control.UIAxes
        distTab                       matlab.ui.container.Tab
        UIAxesDistToTargets           matlab.ui.control.UIAxes
        UIAxesBetwDrones              matlab.ui.control.UIAxes
        PosTab                        matlab.ui.container.Tab
        Console                       matlab.ui.control.TextArea
        InfoTextAreaLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        backend;
        IsRunning = false;
        
        %graphique 3D
        targets;
        heads;
        quiver_handles;
        nb_donnees_courbe; %2D
        targetColors;
        droneViewOn = false;

        %temps
        simu_duration;
        dt;
        time_list = []
        current_time;

        %dimension environnement
        dims;

        %graphiques 2D
        avg_pos_history = [];    % Historique de la norme des positions
        avg_pos_x_history = [];  % Historique des positions moyennes en X
        avg_pos_y_history = [];  
        avg_pos_z_history = [];  
        avg_dist_drone_to_drone_history = [];
        avg_dist_to_target_history;

    end
    
    methods (Access = private)
        
        function update(app)
            tic_var = tic;

            app.backend.update();
            % Pause optionnelle pour contrôler la vitesse de visualisation
            pause(0.001);  % Ajuster ou supprimer selon les besoins
            %drawnow limitrate;
            
            app.current_time = app.current_time + app.dt;
            app.time_list(end+1) = app.current_time;
            %app.tempsrestantEditField.Value = num2str(app.tempsrestantEditField.Value - app.dt);
            %app.tempscouleEditField.Value = num2str(app.tempscouleEditField.Value + app.dt);
            
            app.updatePlot();
            app.update2DPlot();

            app.updateFields(tic_var);
        end
        
        function RunSimulation(app)
            while app.IsRunning
                app.update();
                drawnow;
            end

        end
        
        function Init3DPlot(app)
            %thème sombre
            %app.UIFigure.Color = [0.15 0.15 0.15];
            %set(app.UIAxes3D, 'Color', [0.15 0.15 0.15]);

            app.UIAxes3D.XLim = [app.dims(1,1), app.dims(1,2)];
            app.UIAxes3D.YLim = [app.dims(2,1), app.dims(2,2)];
            app.UIAxes3D.ZLim = [app.dims(3,1), app.dims(3,2)];

            xlabel(app.UIAxes3D, 'X');  
            ylabel(app.UIAxes3D, 'Y');  
            zlabel(app.UIAxes3D, 'Altitude (Z)'); 
            title(app.UIAxes3D, 'Rendu de Simulation en Temps Réel'); 
            
            hold(app.UIAxes3D, 'on');

            fill3(app.UIAxes3D, app.backend.env.GroundCoordinates.x, app.backend.env.GroundCoordinates.y, app.backend.env.GroundCoordinates.z, ...
                [0.5 0.5 0.5], 'FaceAlpha', 0.3); % Couleur grise avec transparence

            %Affichage des zones (obstacles)
            for i = 1:length(app.backend.env.ZonesList)
                zone = app.backend.env.ZonesList{i};
        
                % Déterminer la couleur selon la catégorie de la zone
                switch zone.Category
                    case 'A'
                        zoneColor = 'g'; % Zones autorisées en vert
                    case 'P'
                        zoneColor = 'r'; % Zones prohibées en rouge
                    case 'M'
                        zoneColor = 'b'; % Zones de manœuvre en bleu
                    otherwise
                        zoneColor = 'y'; % Autres zones en jaune
                end
        
                % Créer la forme de la zone avec le type, la couleur, la position et les dimensions spécifiées
                create_shape(zone.Type, zoneColor, zone.CenterPosition, zone.Dimensions, zone.TiltAngle, app.UIAxes3D);
            end
            
            %Affichage des drones et des targets
            targets = app.backend.swarm.target_list;
            app.targets = gobjects(1, size(targets,1));
            for k = 1:size(targets,1)
                app.targets(k)=scatter3(app.UIAxes3D, targets(k,1), targets(k,2), targets(k,3), 30, 'filled', ...
                    'MarkerFaceColor', app.targetColors(k, :), 'DisplayName', ['Target ', num2str(k)]);
            end
        
            legend(app.UIAxes3D, app.targets, 'Location', 'best'); 

            n_drone=size(app.backend.swarm.Drones,2);
            drone_pos=zeros(n_drone,3);
            for i=1:n_drone
                drone_pos(i,:)=app.backend.swarm.AliveDrones{i}.posState;
        
            end
        
            % Initialiser les objets de tracé pour les éléments animés    
            app.heads=gobjects(1,n_drone);
            app.quiver_handles=gobjects(1,n_drone);
        
            for i=1:n_drone
                app.heads(i)=scatter3(app.UIAxes3D, drone_pos(i,1), drone_pos(i,2), drone_pos(i,3), 30, 'filled', 'MarkerFaceColor', 'b', 'HandleVisibility', 'off');
                app.quiver_handles(i)=quiver3(app.UIAxes3D, drone_pos(i,1), drone_pos(i,2), drone_pos(i,3), 0, 0, 0, 'r', 'LineWidth', 1.5, 'MaxHeadSize', 0.5, 'HandleVisibility', 'off');
            end
            
            % !! à corriger !! (vue depuis un drone)
            if ~app.droneViewOn
                campos(app.UIAxes3D, [0, -0, 75]);
                app.UIAxes3D.View = [45, 30];
            else
                list = app.backend.swarm.AliveDrones
                drone = list(randi([1, length(list)]));
                campos(app.UIAxes3D, [drone.posState(1), drone.posState(2), drone.posState(3)]);

            end
        end

        function Init2DPlot(app)
            xlabel(app.UIAxesDistToTargets, 'Time Steps');
            ylabel(app.UIAxesDistToTargets, 'Avg Dist');
            title(app.UIAxesDistToTargets, 'Average distance swarm/targets');
            legend(app.UIAxesDistToTargets, 'show');
            grid(app.UIAxesDistToTargets, 'on');

            xlabel(app.UIAxesBetwDrones, 'Time Steps');
            ylabel(app.UIAxesBetwDrones, 'Avg Dist');
            title(app.UIAxesBetwDrones, 'Average distance drone/drone');
            grid(app.UIAxesBetwDrones, 'on');
        end

        function updatePlot(app)
            hold(app.UIAxes3D, 'on');
            n_drone=size(app.backend.swarm.Drones,2);
            drone_pos=zeros(n_drone,3);
            drone_speed=zeros(n_drone,3);
            for i=1:n_drone
                drone = app.backend.swarm.Drones{i};
                drone_pos(i,:)=drone.posState;
                drone_speed(i,:)=drone.speedState;
                if drone.IsAlive
                    set(app.heads(i), 'XData', drone_pos(i,1), 'YData', drone_pos(i,2), 'ZData', drone_pos(i,3));
                    set(app.quiver_handles(i), 'XData', drone_pos(i,1), 'YData', drone_pos(i,2), 'ZData', drone_pos(i,3), ...
                     'UData', drone_speed(i,1), 'VData', drone_speed(i,2), 'WData', drone_speed(i,3));
                else
                    set(app.heads(i), 'XData', drone_pos(i,1), 'YData', drone_pos(i,2), 'ZData', drone_pos(i,3), 'MarkerFaceColor', [0 0 0]);
                end
            end
            
        end

        function updateTargetPos(app)
            targets = app.backend.swarm.target_list;
            for k = 1:size(targets, 1)
                set(app.targets(k), 'XData', targets(k, 1), 'YData', targets(k, 2), 'ZData', targets(k, 3));
            end
            
        end
        
        function update2DPlot(app)
            if length(app.time_list) > app.nb_donnees_courbe
                app.time_list(1) = [];
                app.vg_dist_drone_to_drone_history(1) = [];
                for i = 1:numel(app.avg_dist_to_target_history)
                    if ~isempty(app.avg_dist_to_target_history{i})
                        app.avg_dist_to_target_history{i}(1) = [];  % Supprime le premier élément
                    end
                end
            end

            % calculs et affichage distance moyenne à chaque cible

            drones_data = app.backend.swarm.AliveDrones;
            targets_data = app.backend.swarm.target_list;
            avg_pos_x = 0;
            avg_pos_y = 0;
            avg_pos_z = 0;
            for i = 1: length(drones_data)

                avg_pos_x = avg_pos_x + drones_data{i}.posState(1);
                avg_pos_y = avg_pos_y + drones_data{i}.posState(2);
                avg_pos_z = avg_pos_z + drones_data{i}.posState(3);
            end

            avg_pos_x = avg_pos_x /length(drones_data);
            avg_pos_y = avg_pos_y /length(drones_data);
            avg_pos_z = avg_pos_z /length(drones_data);

            avg_pos = sqrt(avg_pos_x^2 + avg_pos_y^2 + avg_pos_z^2);
            app.avg_pos_history(end+1) = avg_pos;
            app.avg_pos_x_history(end+1) = avg_pos_x;
            app.avg_pos_y_history(end+1) = avg_pos_y;
            app.avg_pos_z_history(end+1) = avg_pos_z;
            
            hold(app.UIAxesDistToTargets, 'on');
            cla(app.UIAxesDistToTargets); % Nettoyage du tracé précédent 

            for i = 1: size(targets_data, 1)
                x = targets_data(i, 1);
                y = targets_data(i, 2);
                z = targets_data(i, 3);

                dist_to_target = sqrt((x - avg_pos_x)^2 + (y - avg_pos_y)^2 + (z - avg_pos_z)^2);
                app.avg_dist_to_target_history{i} = [app.avg_dist_to_target_history{i}, dist_to_target];
                
            end
            
            for i = 1:numel(app.avg_dist_to_target_history)
                data = app.avg_dist_to_target_history{i};  % Extraire les distances de la cellule i
                
                if ~isempty(data)  % Vérifier que la cellule contient des données
                    plot(app.UIAxesDistToTargets, app.time_list, data, '-', 'LineWidth', 1, ...
                        'DisplayName', ['Target ', num2str(i)], 'MarkerFaceColor', app.targetColors(i, :));
                end
            end
            
            % calculs et affichage distances moyennes entre drones
            
            avg_dist = 0;
            nb = length(drones_data);
            for i = 1: nb
                for k = i+1: nb
                    avg_dist = avg_dist + sqrt((drones_data{i}.posState(1) - drones_data{k}.posState(1))^2 + (drones_data{i}.posState(2) - drones_data{k}.posState(2))^2 + (drones_data{i}.posState(3) - drones_data{k}.posState(3))^2);
                end
            end
            avg_dist = avg_dist*2/(nb*(nb-1));
            app.avg_dist_drone_to_drone_history(end+1) = avg_dist;

            plot(app.UIAxesBetwDrones, app.time_list, app.avg_dist_drone_to_drone_history, '-', 'LineWidth', 1);

            drawnow limitrate;
        end


        function InitTargetDropDown(app)
            targetNames = {};
            for i = 1:size(app.backend.targetList, 1)
                targetNames{end+1} = [num2str(i)];
            end
            app.TargetDropDown.Items = targetNames;
            %app.TargetDropdown.Value = '1';
        end

        function InitSlider(app)
            %app.Slider.Limits = app.backend.env.GroundDimensions;
            app.Slider.Limits = [-200 200];
            app.onDropDownUpdate;
        end
        
        
        function updateFields(app, tic_var)
            app.tempsrestantEditField.Value = sprintf('%.1f s', app.simu_duration - app.current_time);
            app.tempscouleEditField.Value = sprintf('%.1f s', app.current_time);
            fps = 1/toc(tic_var);
            app.FPSEditField.Value = fps;
        end
        
        function onDropDownUpdate(app)
            nb_drone = str2num(app.TargetDropDown.Value);
            index_coor = find(strcmp(app.CoorDropDown.Items, app.CoorDropDown.Value));
            value = app.backend.swarm.target_list(nb_drone, index_coor);
            
            app.Slider.Limits = [app.dims(index_coor,1), app.dims(index_coor,2)]
            
            app.Slider.Value = value;
            app.DronePosField.Value = value;
        end
        
    end
    
    methods (Access = public)
        
        function OnDronesCollision(app, ID1, ID2)
            disp(['collision entre les drones ' num2str(ID1) ' et ' num2str(ID2)])
            % Création du message à afficher
            message = sprintf('Collision entre drone %d et drone %d', ID1, ID2);     
            currentMessages = app.Console.Value;  
            if isempty(currentMessages{1})  
                currentMessages = currentMessages(2:end);  
            end
            currentMessages{end+1} = message;  
            app.Console.Value = currentMessages;

            delete(app.quiver_handles(ID1));
            delete(app.quiver_handles(ID2));

        end

        function OnObstacleCollision(obj, i, k)

        end
        
        function OnDronesNbUpdate(app)
            app.DronesrestantsEditField.Value = length(app.backend.swarm.AliveDrones);
            app.DronesperdusEditField.Value = length(app.backend.swarm.DeadDrones);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.backend = AppBackend();
            app.backend.Init(app);

            settings = app.backend.settings;

            app.simu_duration = settings.temps;
            app.dt = settings.dt;
            app.current_time =0.0;

            app.dims = app.backend.settings.dims;
            app.nb_donnees_courbe = app.backend.settings.nb_donnees_courbe;

            app.DronesrestantsEditField.Value = length(app.backend.swarm.AliveDrones);
            app.DronesperdusEditField.Value = length(app.backend.swarm.DeadDrones);
            
            app.targetColors = lines(size(app.backend.swarm.target_list, 1));

            app.avg_dist_to_target_history = cell(size(app.backend.swarm.target_list, 1), 1);

            app.tempsrestantEditField.Value = sprintf('%.1f s', app.simu_duration);
            app.tempscouleEditField.Value = '0 s';

            app.Init2DPlot();
            app.Init3DPlot();
            app.InitTargetDropDown();
            app.InitSlider();

        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)

            if ~app.IsRunning
                app.IsRunning = true;
                app.RunButton.Text = 'Pause';
                app.Lamp.Color = [0.00,1.00,0.00];
                RunSimulation(app);
            else
                app.IsRunning = false;
                app.RunButton.Text = 'Run';
                app.Lamp.Color = [1.00,0.00,0.00];
            end


        end

        % Value changed function: TargetDropDown
        function TargetDropDownValueChanged(app, event)
            app.onDropDownUpdate();
            
        end

        % Value changed function: CoorDropDown
        function CoorDropDownValueChanged(app, event)
            app.onDropDownUpdate();
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            nb_drone = str2num(app.TargetDropDown.Value);
            index_coor = find(strcmp(app.CoorDropDown.Items, app.CoorDropDown.Value));
            new_value = app.Slider.Value;

            new_target_list = app.backend.swarm.target_list;
            new_target_list(nb_drone, index_coor) = new_value;
            
            app.DronePosField.Value = new_value;

            app.backend.swarm.update_target(new_target_list);
            app.updateTargetPos();
            
        end

        % Button pushed function: VuespectateurButton
        function VuespectateurButtonPushed(app, event)
            app.droneViewOn = ~app.droneViewOn;
            if app.droneViewOn
                app.VuespectateurButton.Text = "Vue drone";
            else
                app.VuespectateurButton.Text = "Vue spectateur"
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DviewMenu = uimenu(app.UIFigure);
            app.DviewMenu.Text = '3Dview';
            app.InfoMenu = uimenu(app.UIFigure);
            app.InfoMenu.Text = 'Info';
            app.Menu = uimenu(app.InfoMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.InfoMenu);
            app.Menu2.Text = 'Menu2';
            app.InfoTextAreaLabel = uilabel(app.UIFigure);
            app.InfoTextAreaLabel.Text = 'Info';
            app.Console = uitextarea(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DviewTab = uitab(app.TabGroup);
            app.DviewTab.Title = '3Dview';
            app.UIAxes3D = uiaxes(app.DviewTab);
            title(app.UIAxes3D, '3DGraph')
            xlabel(app.UIAxes3D, 'X')
            ylabel(app.UIAxes3D, 'Y')
            zlabel(app.UIAxes3D, 'Z')
            app.distTab = uitab(app.TabGroup);
            app.distTab.Title = 'dist';
            app.UIAxesBetwDrones = uiaxes(app.distTab);
            title(app.UIAxesBetwDrones, 'dist. between drones')
            xlabel(app.UIAxesBetwDrones, 'X')
            ylabel(app.UIAxesBetwDrones, 'Y')
            zlabel(app.UIAxesBetwDrones, 'Z')
            app.UIAxesDistToTargets = uiaxes(app.distTab);
            title(app.UIAxesDistToTargets, 'dist to targets')
            xlabel(app.UIAxesDistToTargets, 'X')
            ylabel(app.UIAxesDistToTargets, 'Y')
            zlabel(app.UIAxesDistToTargets, 'Z')
            app.PosTab = uitab(app.TabGroup);
            app.PosTab.Title = 'Pos';
            app.Panel = uipanel(app.UIFigure);
            app.RunButton = uibutton(app.Panel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Lamp = uilamp(app.Panel);
            app.SliderLabel = uilabel(app.Panel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.Panel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.EditFieldLabel = uilabel(app.Panel);
            app.EditFieldLabel.Text = 'Edit Field';
            app.DronePosField = uieditfield(app.Panel, 'numeric');
            app.TargetDropDownLabel = uilabel(app.Panel);
            app.TargetDropDownLabel.Text = 'Target';
            app.TargetDropDown = uidropdown(app.Panel);
            app.TargetDropDown.Items = {'1', '2'};
            app.TargetDropDown.ValueChangedFcn = createCallbackFcn(app, @TargetDropDownValueChanged, true);
            app.TargetDropDown.Value = '1';
            app.CoorDropDownLabel = uilabel(app.Panel);
            app.CoorDropDownLabel.Text = 'Coor.';
            app.CoorDropDown = uidropdown(app.Panel);
            app.CoorDropDown.Items = {'x', 'y', 'z'};
            app.CoorDropDown.ValueChangedFcn = createCallbackFcn(app, @CoorDropDownValueChanged, true);
            app.CoorDropDown.Value = 'x';
            app.FPSEditFieldLabel = uilabel(app.Panel);
            app.FPSEditFieldLabel.Text = 'FPS';
            app.FPSEditField = uieditfield(app.Panel, 'numeric');
            app.DronesrestantsEditFieldLabel = uilabel(app.Panel);
            app.DronesrestantsEditFieldLabel.Text = 'Drones restants';
            app.DronesrestantsEditField = uieditfield(app.Panel, 'numeric');
            app.DronesperdusEditFieldLabel = uilabel(app.Panel);
            app.DronesperdusEditFieldLabel.Text = 'Drones perdus';
            app.DronesperdusEditField = uieditfield(app.Panel, 'numeric');
            app.tempscoulEditFieldLabel = uilabel(app.Panel);
            app.tempscoulEditFieldLabel.Text = 'temps écoulé';
            app.tempscouleEditField = uieditfield(app.Panel, 'text');
            app.tempsrestantEditFieldLabel = uilabel(app.Panel);
            app.tempsrestantEditFieldLabel.Text = 'temps restant';
            app.tempsrestantEditField = uieditfield(app.Panel, 'text');
            app.VuespectateurButton = uibutton(app.Panel, 'push');
            app.VuespectateurButton.ButtonPushedFcn = createCallbackFcn(app, @VuespectateurButtonPushed, true);
            app.VuespectateurButton.Text = 'Vue spectateur';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end