classdef IHM_FINAL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IHMProjetESTACADC2025ContrledunEssaimdeDronesUIFigure  matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        TranslationAVButton            matlab.ui.control.StateButton
        TranslationDRButton            matlab.ui.control.StateButton
        TranslationARButton            matlab.ui.control.StateButton
        TranslationGAButton            matlab.ui.control.StateButton
        Lamp                           matlab.ui.control.Lamp
        DmarrerButton                  matlab.ui.control.Button
        PauseButton                    matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        DronesperdusEditField          matlab.ui.control.NumericEditField
        DronesperdusEditFieldLabel     matlab.ui.control.Label
        EventLoggingLabel              matlab.ui.control.Label
        TempscoulLabel                 matlab.ui.control.Label
        DronesnondploysEditField       matlab.ui.control.NumericEditField
        DronesnondploysEditFieldLabel  matlab.ui.control.Label
        DronesdploysEditField          matlab.ui.control.NumericEditField
        DronesdploysEditFieldLabel     matlab.ui.control.Label
        tempscouleEditField            matlab.ui.control.EditField
        Console                        matlab.ui.control.TextArea
        TabGroup                       matlab.ui.container.TabGroup
        FlotteTab                      matlab.ui.container.Tab
        SuppressionDroneButton         matlab.ui.control.Button
        ModificationDroneButton        matlab.ui.control.Button
        AjoutDroneButton               matlab.ui.control.Button
        UIFlotteTable                  matlab.ui.control.Table
        CiblesTab                      matlab.ui.container.Tab
        TargetsPanel                   matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        TargetSliderZ                  matlab.ui.control.Slider
        Slider_3Label                  matlab.ui.control.Label
        EditTargetFieldZ               matlab.ui.control.EditField
        EditLabel_3                    matlab.ui.control.Label
        TargetSliderY                  matlab.ui.control.Slider
        Slider_2Label                  matlab.ui.control.Label
        EditTargetFieldY               matlab.ui.control.EditField
        EditLabel_2                    matlab.ui.control.Label
        TargetSliderX                  matlab.ui.control.Slider
        SliderLabel                    matlab.ui.control.Label
        EditTargetFieldX               matlab.ui.control.EditField
        EditLabel                      matlab.ui.control.Label
        TargetDropDown                 matlab.ui.control.DropDown
        TargetnameLabel                matlab.ui.control.Label
        ZLabel                         matlab.ui.control.Label
        YLabel                         matlab.ui.control.Label
        XLabel                         matlab.ui.control.Label
        SuppressionCibleButton         matlab.ui.control.Button
        ModificationCibleButton        matlab.ui.control.Button
        AjoutCibleButton               matlab.ui.control.Button
        UICibleTable                   matlab.ui.control.Table
        ZonesTab                       matlab.ui.container.Tab
        SuppressionZoneButton          matlab.ui.control.Button
        ModificationZoneButton         matlab.ui.control.Button
        AjoutZoneButton                matlab.ui.control.Button
        UIZoneTable                    matlab.ui.control.Table
        ObstaclesTab                   matlab.ui.container.Tab
        SuppressionObstacleButton      matlab.ui.control.Button
        ModificationObstacleButton     matlab.ui.control.Button
        AjoutObstacleButton            matlab.ui.control.Button
        UIObstacleTable                matlab.ui.control.Table
        UIAxes3D                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        plotHandles  % struct to store scatter, quiver, trace objects
        simTimer     % MATLAB timer for repeated updates

        OriginalCameraPosition
        OriginalCameraTarget
        OriginalCameraUpVector
        cameraMotionTimer     % Timer for camera movement
        moveStep          double = 5                 % Speed of the translation

        env                  % Environment 
        swarm                % SwarmManager 
        DroneModels
        simulationRunning    logical = false
        simulationPaused     logical = false
    
        % For real-time plotting
        dronePlotHandles            = []
        traceSize                   = 50
        speedVectorSize             = 2
        translationStep             = 10
        dt                   double = 0.1
        UIPeriod             double = 0.1
        simulationTime       double = 0
        maxSimulationTime    double = 100000
        numScenario                 = "2"
    end

    methods (Access = public)
        function exportAllVariables(app)
            assignin('base', 'swarm', app.swarm);
        end
    end

    methods (Access = public)
        function setupSimulation(app)
            app.env = Environment(10, 500, ...
                [-500, 500, 500, -500], ...
                [-500, -200, 500, 500], ...
                [0,0,0,0]);

            app.swarm = SwarmManager(app.env, app.maxSimulationTime);

            homeBaseCoord = [0 0 0];
            
            % app.importationObstacles("obstaclelist.csv");
            app.importationModelesDrones("dronemodels.csv");
            path = strcat(pwd, "/databases/scenarios/", "scenario", app.numScenario, "/");
            app.importationCibles(strcat(path, "targets.csv"));
            app.importationZones(strcat(path, "zones.csv"));
            app.importationFlotte(strcat(path, "fleet.csv"), homeBaseCoord);

            for i = 1:length(app.swarm.Drones)
                app.swarm.Drones{i}.mode_Follow_waypoint = false; 
            end

            Waypoints = [0 50 100; 0 0 50; 100 100 50 ; 100 -100 100 ; ...
            -100 -100 50 ; -100 100 100 ; -100 -10 10]; 
            for i = 1:length(app.swarm.Drones)
                app.swarm.Drones{i}.Waypoints = Waypoints;
            end
        
            app.swarm.Drones{end}.setTargetGroup(2);
            app.swarm.Drones{1}.mode_Follow_waypoint = true;
           
            app.swarm.r = [30 60 100]; 
            app.swarm.swarm_weights = [1.4 0.8]; 
            app.swarm.weights = [0.5 1.2 1 10] / 10;
            
            app.plotHandles = RTPlot2_init(app, app.UIAxes3D, app.env, app.swarm, app.traceSize);
            RTPlot2_update(app, app.plotHandles, app.swarm);

            updateZoneTable(app);
            updateObstacleTable(app);
            updateTargetTable(app);
            updateDroneTable(app);

            drawnow;
        end

        function startSimulationTimer(app)
            if isempty(app.simTimer) || ~isvalid(app.simTimer)
                app.simTimer = timer( ...
                    'ExecutionMode','fixedRate', ...
                    'Period', 0.2, ...
                    'BusyMode','drop', ...
                    'TimerFcn', @(~,~)updateSimulation(app));
            else
                if strcmpi(app.simTimer.Running, 'on')
                    stop(app.simTimer);
                end
                app.simTimer.Period = 0.2;
                app.simTimer.TimerFcn = @(~,~)updateSimulation(app);
            end
        
            start(app.simTimer);
            app.simulationRunning = true;
            app.simulationPaused  = false;
            app.Lamp.Color = [0 1 0];  % Vert
            addConsoleMsg(app, "Simulation démarrée.");
        end

        function updateSimulation(app)
            if ~app.simulationPaused
                app.simulationTime = app.simulationTime + app.dt;

                app.swarm.update_speeds(app.dt);

                RTPlot2_update(app, app.plotHandles, app.swarm);

                dronesStandby = 0;
                dronesPerdus = 0;

                for i = 1:length(app.swarm.Drones)
                    if app.swarm.Drones{i}.IsAlive == false
                        dronesPerdus = dronesPerdus + 1;
                    else
                        if app.swarm.Drones{i}.phase == "standby"
                            dronesStandby = dronesStandby + 1;
                        end
                    end
                end
                
                dronesDeployes = length(app.swarm.Drones) - (dronesStandby + dronesPerdus);

                app.DronesdploysEditField.Value    = dronesDeployes;
                app.DronesnondploysEditField.Value = dronesStandby;
                app.DronesperdusEditField.Value    = dronesPerdus;

                app.tempscouleEditField.Value = sprintf("%.1f", app.simulationTime);

                updateTargetTable(app);
                updateDroneTable(app);
                updateZoneTable(app);
                updateObstacleTable(app);

                %exportAllVariables(app);

                drawnow limitrate;

                if app.simulationTime >= app.maxSimulationTime
                    pauseSimulationTimer(app);
                end
            end
        end


        function importationModelesDrones(app, filename)
            T = readtable(filename, 'Delimiter', ',');
            
            requiredCols = {'Model','Type','MaxSpeed','MinSpeed','CruiseSpeed',...
                            'MaxVarioUp','MaxVarioDwn','MaxTurnGLoad','Mass',...
                            'RotorNumber','RotorDiameter','NominalCapacity','NominalVoltage',...
                            'TankVolume','AutonomyMins','ReloadMins','Finesse'};
            for c = 1:numel(requiredCols)
                if ~ismember(requiredCols{c}, T.Properties.VariableNames)
                    error('Colonne "%s" manquante dans le fichier "%s".', ...
                          requiredCols{c}, filename);
                end
            end

            app.DroneModels = T;
            
            fprintf('%d modeles de drones ajoutes depuis "%s".\n', height(T), filename);
        end

        function importationFlotte(app, filename, homeBaseCoord)
            T = readtable(filename, 'Delimiter', ',');

            requiredCols = {'Name','Model'};
            for c = 1:numel(requiredCols)
                if ~ismember(requiredCols{c}, T.Properties.VariableNames)
                    error('Colonne "%s" manquante dans le fichier "%s".', ...
                          requiredCols{c}, filename);
                end
            end
            
            n = height(T);
            for i = 1:n
                droneName = T.Name{i};
                droneModel = T.Model{i};

                idx = find(strcmp(app.DroneModels.Model, droneModel));
                if isempty(idx)
                    error('Modèle "%s" inexistant.', droneModel);
                end

                idx = idx(1);
                modelData = app.DroneModels(idx, :);
                
                dType = modelData.Type;
                if iscell(dType)
                    dType = dType{1};
                end

                app.swarm.addDrone(dType, droneName, modelData, homeBaseCoord);
            end
            
            fprintf('%d drones ajoutes depuis "%s".\n', height(T), filename);
        end

        function importationCibles(app, filename)
            T = readtable(filename, 'Delimiter', ',');

            requiredCols = {"Name", "CenterX", "CenterY", "CenterZ", ...
                          "Status", "ObservabilityScore"};
            for c = 1:numel(requiredCols)
                if ~ismember(requiredCols{c}, T.Properties.VariableNames)
                    error('Colonne "%s" manquante dans le fichier "%s".', ...
                          requiredCols{c}, filename);
                end
            end

            for i = 1:height(T)
                name  = T.Name{i};
                pos   = [T.CenterX(i), T.CenterY(i), T.CenterZ(i)];
                stat  = T.Status{i};
                score = T.ObservabilityScore(i);
    
                newTarget = Target(name, pos, stat, score, app.env);

                app.env.addTarget(newTarget);
            end
            
            fprintf('%d cibles ajoutees depuis "%s".\n', height(T), filename);
        end

        function importationZones(app, filename)
            T = readtable(filename, 'Delimiter', ',');

            requiredCols = ["Name","Type","Category","CenterX","CenterY","CenterZ", ...
                          "Dim1","Tilt","Status"];
            for c = 1:numel(requiredCols)
                if ~ismember(requiredCols{c}, T.Properties.VariableNames)
                    error('Colonne "%s" manquante dans le fichier "%s".', ...
                          requiredCols{c}, filename);
                end
            end

            for i = 1:height(T)
                name     = T.Name{i};
                ztype    = T.Type{i};
                cat      = T.Category{i};
                center   = [T.CenterX(i), T.CenterY(i), T.CenterZ(i)];
                dims     = T.Dim1(i);
                tilt     = T.Tilt(i);
                stat     = T.Status{i};

                newZone = Zone(name, ztype, cat, center, dims, tilt, stat, app.env);

                app.env.addZone(newZone);
            end
            
            fprintf('%d zones ajoutees depuis "%s".\n', height(T), filename);
        end

        function importationObstacles(app, filename)
            T = readtable(filename, 'Delimiter', ',');

            requiredCols = {"Name","Type","Category","CenterX","CenterY","CenterZ", ...
                          "Dim1","TiltAngle","Status"};
            for c = 1:numel(requiredCols)
                if ~ismember(requiredCols{c}, T.Properties.VariableNames)
                    error('Colonne "%s" manquante dans le fichier "%s".', ...
                          requiredCols{c}, filename);
                end
            end

            for i = 1:height(T)
                name  = T.Name{i};
                otype = T.Type{i};
                cat   = T.Category{i};
                center = [T.CenterX(i), T.CenterY(i), T.CenterZ(i)];
                dims   = T.Dim1(i);
                tilt   = T.TiltAngle(i);
                stat   = T.Status{i};

                newObstacle = Obstacle(name, otype, cat, center, dims, tilt, stat, app.env);

                app.env.addObstacle(newObstacle);
            end
            
            fprintf('%d obstacles ajoutes depuis "%s".\n', height(T), filename);
        end


        %% ATTENTION BESOIN DE CHECKER LE DISPLAY WAYPOINT
        function handles = RTPlot2_init(app, ax, env, swarm, traceSize)
            grid(ax, 'on');
            hold(ax, 'on');
            axis(ax, 'equal');
            zoom(ax, 'on');
            pan(ax, 'off');
            rotate3d(ax, 'on');

            set(ax, ...
                'XLim', [-200, 200], ...
                'YLim', [-200, 200], ...
                'ZLim', [0, 100], ...
                'XLimMode', 'manual', ...
                'YLimMode', 'manual', ...
                'ZLimMode', 'auto');

            view(ax, 10, 25);
        
            xlabel(ax, 'X (Nord - Sud)');
            ylabel(ax, 'Y (Ouest - Est)');
            zlabel(ax, 'Z (Altitude)');
            title(ax, 'Rendu de Simulation en Temps Réel');

            fill3(ax, ...
                env.GroundCoordinates.x, ...
                env.GroundCoordinates.y, ...
                env.GroundCoordinates.z, ...
                [0.8 0.8 0.8], 'FaceAlpha', 1);

            nZones = length(env.ZonesList);
            zoneHandles = gobjects(1, nZones);
            for iZone = 1:nZones
                zone = env.ZonesList{iZone};
                zoneColor = app.pickZoneColor(zone.Category, zone.Status);
        
                shapeHandle = create_shape(zone.Type, zoneColor, ...
                    zone.CenterPosition, zone.Dimensions, ...
                    zone.TiltAngle, ax);
                zoneHandles(iZone) = shapeHandle;
            end

            nObstacles = length(env.ObstaclesList);
            obstacleHandles = gobjects(1, nObstacles);
            for iObstacle = 1:nObstacles
                obstacle = env.ObstaclesList{iObstacle};
                obstacleColor = app.pickZoneColor(obstacle.Category, obstacle.Status);
        
                shapeHandle = create_shape(obstacle.Type, obstacleColor, ...
                    obstacle.CenterPosition, obstacle.Dimensions, ...
                    obstacle.TiltAngle, ax);
                obstacleHandles(iObstacle) = shapeHandle;
            end

            nTargets = length(env.TargetsList);
            targetHandles = gobjects(1, nZones);
            for iTarget = 1:nTargets
                target = env.TargetsList{iTarget};
                targetColor = app.pickTargetColor(target.Status);
        
                targetHandles(iTarget) = scatter3(ax, ...
                target.Position(1), target.Position(2), target.Position(3), ...
                50, 'filled', 'MarkerFaceColor',targetColor);
            end
        
            nDrones = numel(swarm.Drones);
            droneHeads   = gobjects(1, nDrones);
            droneQuivers = gobjects(1, nDrones);
            droneTraces  = gobjects(1, nDrones);
        
            for i = 1:nDrones
                drone = swarm.Drones{i};
                pos = drone.posState;
                drone.phase
                droneColor = app.pickDroneColor(drone.phase);
                droneHeads(i) = scatter3(ax, ...
                    pos(1), pos(2), pos(3), 50, ...
                    'filled', 'MarkerFaceColor', droneColor);
        
                droneQuivers(i) = quiver3(ax, ...
                    pos(1), pos(2), pos(3), ...
                    0, 0, 0, "r", 'LineWidth', 1.5, 'MaxHeadSize', 0.5);
        
                droneTraces(i) = plot3(ax, nan, nan, nan, ...
                    'Color', droneColor, 'LineStyle', '--');
            end
        
            %% == 5) Plot the Active Waypoints (FixedWing) ====================
            nFixedWing = numel(swarm.FixedWing);
            waypointHandles = gobjects(1, nFixedWing);
            for i = 1:nFixedWing
                fw = swarm.FixedWing{i};
                if ~isempty(fw.Waypoints)
                    cwp = fw.CurrentWaypoint;
                    if cwp <= size(fw.Waypoints, 1)
                        wpPos = fw.Waypoints(cwp, :);
                        waypointHandles(i) = scatter3(ax, ...
                            wpPos(1), wpPos(2), wpPos(3), ...
                            50, 'filled', 'MarkerFaceColor',[0.2660 0.8740 0.1880]);
                    else
                        waypointHandles(i) = scatter3(ax, NaN, NaN, NaN, ...
                            50, 'filled', 'MarkerFaceColor',[0.2660 0.8740 0.1880]);
                    end
                else
                    waypointHandles(i) = scatter3(ax, NaN, NaN, NaN, ...
                        50, 'filled', 'MarkerFaceColor',[0.2660 0.8740 0.1880]);
                end
            end
        
            %== 6) Package into a "handles" struct ==========================
            handles.ax               = ax;
            handles.zoneHandles      = zoneHandles;
            handles.targetHandles    = targetHandles;
            handles.obstacleHandles  = obstacleHandles;
            handles.droneHeads       = droneHeads;
            handles.droneQuivers     = droneQuivers;
            handles.droneTraces      = droneTraces;
            handles.waypointHandles  = waypointHandles;
            handles.traceSize        = traceSize;
            handles.speedVectorSize  = app.speedVectorSize;
            handles.nZonesLastUpdate = nZones;
            handles.nObstaclesLastUpdate = nObstacles;
            handles.nTargetsLastUpdate = nTargets;
        end

        function RTPlot2_OnTargetPosChange(app)
            nTargets = length(app.env.TargetsList);
            handles = app.plotHandles.targetHandles;
            
            for iTarget = 1:nTargets
                target = app.env.TargetsList{iTarget};
                targetColor = app.pickTargetColor(target.Status);
        
                set(handles(iTarget), ...
                'XData', target.Position(1), ...
                'YData', target.Position(2), ...
                'ZData', target.Position(3), ...
                'MarkerFaceColor', targetColor);
            end
        end

        %% ATTENTION BESOIN DE CHECKER LE DISPLAY WAYPOINT
        function RTPlot2_update(app, handles, swarm)
            ax = handles.ax;
            speedVectorSz = handles.speedVectorSize;
            app.traceSize = handles.traceSize;

            nDrones = numel(swarm.Drones);
        
            for i = 1:nDrones
                drone = swarm.Drones{i};
                pos = drone.posState;
                spd = drone.speedState;

                droneColor = app.pickDroneColor(drone.phase);

                set(handles.droneHeads(i), ...
                    'XData', pos(1), 'YData', pos(2), 'ZData', pos(3), ...
                    'MarkerFaceColor', droneColor);

                set(handles.droneQuivers(i), ...
                    'XData', pos(1), 'YData', pos(2), 'ZData', pos(3), ...
                    'UData', spd(1)*speedVectorSz, ...
                    'VData', spd(2)*speedVectorSz, ...
                    'WData', spd(3)*speedVectorSz);

                if isprop(drone, 'posLog')
                    lastPositions = drone.posLog;
                    if ~isempty(lastPositions)
                        idx = max(1, size(lastPositions,1) - app.traceSize + 1) : size(lastPositions,1);
                        recentPositions = lastPositions(idx, :);
                        set(handles.droneTraces(i), ...
                            'XData', recentPositions(:,1), ...
                            'YData', recentPositions(:,2), ...
                            'ZData', recentPositions(:,3), ...
                            'Color', droneColor);
                    end
                end
            end
        
            %% --- 2) Update Waypoints (FixedWing) ---
            nFixedWing = numel(swarm.FixedWing);
            for i = 1:nFixedWing
                fw = swarm.FixedWing{i};

                if ~isempty(fw.Waypoints) ...
                        && fw.CurrentWaypoint >= 1 ...
                        && fw.CurrentWaypoint <= size(fw.Waypoints, 1)

                    cwp   = fw.CurrentWaypoint;
                    wpPos = fw.Waypoints(cwp, :);
                    set(handles.waypointHandles(i), ...
                        'XData', wpPos(1), 'YData', wpPos(2), 'ZData', wpPos(3));
                else
                    set(handles.waypointHandles(i), ...
                        'XData', NaN, 'YData', NaN, 'ZData', NaN);
                end
            end
        
            newZones = app.env.ZonesList;
            newZoneCount = length(newZones);
            oldZoneCount = handles.nZonesLastUpdate;
        
            if newZoneCount ~= oldZoneCount
                oldHandles = handles.zoneHandles;
                for hh = 1:length(oldHandles)
                    if isvalid(oldHandles(hh))
                        delete(oldHandles(hh));
                    end
                end
        
                newZoneHandles = gobjects(1, newZoneCount);
                for iZone = 1:newZoneCount
                    zone = newZones{iZone};
                    zoneColor = app.pickZoneColor(zone.Category, zone.Status);
                    shapeHandle = create_shape(zone.Type, zoneColor, ...
                                               zone.CenterPosition, zone.Dimensions, ...
                                               zone.TiltAngle, ax);
                    newZoneHandles(iZone) = shapeHandle;
                end

                handles.zoneHandles      = newZoneHandles;
                handles.nZonesLastUpdate = newZoneCount;
            end

            newObstacles = app.env.ObstaclesList;
            newObstacleCount = length(newObstacles);
            oldObstacleCount = handles.nObstaclesLastUpdate;

            if newObstacleCount ~= oldObstacleCount
                oldHandles = handles.obstacleHandles;
                for hh = 1:length(oldHandles)
                    if isvalid(oldHandles(hh))
                        delete(oldHandles(hh));
                    end
                end
        
                newObstacleHandles = gobjects(1, newObstacleCount);
                for iObstacle = 1:newObstacleCount
                    obstacle = newObstacles{iObstacle};
                    obstacleColor = app.pickZoneColor(obstacle.Category, obstacle.Status);
            
                    shapeHandle = create_shape(obstacle.Type, obstacleColor, ...
                        obstacle.CenterPosition, obstacle.Dimensions, ...
                        obstacle.TiltAngle, ax);
                    newObstacleHandles(iObstacle) = shapeHandle;
                end

                handles.obstacleHandles      = newObstacleHandles;
                handles.nObstaclesLastUpdate = newObstacleCount;
            end

            newTargets = app.env.TargetsList;
            newTargetCount = length(newTargets);
            oldTargetCount = handles.nTargetsLastUpdate;

            if newTargetCount ~= oldTargetCount
                oldHandles = handles.targetHandles;
                for hh = 1:length(oldHandles)
                    if isvalid(oldHandles(hh))
                        delete(oldHandles(hh));
                    end
                end
        
                newTargetHandles = gobjects(1, newTargetCount);
                for iTarget = 1:newTargetCount
                    target = newTargets{iTarget};
                    targetColor = app.pickTargetColor(target.Status);
            
                    targetHandles = scatter3(ax, ...
                        target.Position(1), target.Position(2), target.Position(3), ...
                        50, 'filled', 'MarkerFaceColor',targetColor);
                    newTargetHandles(iTarget) = targetHandles;
                end

                handles.targetHandles      = newTargetHandles;
                handles.nTargetsLastUpdate = newTargetCount;
            end
        end

        function c = pickZoneColor(app, category, status)
            switch status
                case 'actif'
                    switch category
                        case 'A'
                            c = 'g';
                        case 'P'
                            c = 'r';
                        case 'M'
                            c = 'b';
                        otherwise
                            c = 'y';
                    end
                otherwise
                    c = 'k';
            end
        end

        function c = pickTargetColor(app, status)

            switch status
                case 'actif'
                    c = [0.4940 0.1840 0.5560];
                otherwise
                    c = [0.25 0.25 0.25];
            end
        end
   
        function c = pickDroneColor(app, phase)
            switch phase
                case 'take-off'
                    c = [0.2660 0.8740 0.1880];
                case 'inbound'
                    c = [0.2660 0.8740 0.1880];
                case 'airborn'
                    c = [0 0.4470 0.7410];
                case 'return'
                    c = [0.9290 0.5940 0.1250];
                case 'landing'
                    c = [0.9290 0.5940 0.1250];
                otherwise
                    c = [0.25 0.25 0.25];
            end
        end

        function moveCameraContinuous(app)
            % We'll look at each of the four buttons' .Value to see if it's pressed.
            % Then we compute the net translation direction.
    
            % 0) Grab camera data
            camPos = app.UIAxes3D.CameraPosition;
            camTgt = app.UIAxes3D.CameraTarget;
            upVec  = app.UIAxes3D.CameraUpVector;
    
            forwardVec = camTgt - camPos;
            forwardNorm = forwardVec / norm(forwardVec);  % direction forward
    
            % "Left" vector can be cross(UpVector, forward)
            leftVec   = cross(upVec, forwardNorm);
            leftNorm  = leftVec / norm(leftVec);
            rightNorm = -leftNorm;   % or cross(forwardNorm, upVec), etc.
    
            % We'll accumulate a netDirection based on pressed buttons
            netDir = [0 0 0];
    
            % 1) Forward
            if app.TranslationAVButton.Value == 1
                netDir = netDir + forwardNorm;
            end
            % 2) Backward
            if app.TranslationARButton.Value == 1
                netDir = netDir - forwardNorm;
            end
            % 3) Left
            if app.TranslationGAButton.Value == 1
                netDir = netDir + leftNorm;
            end
            % 4) Right
            if app.TranslationDRButton.Value == 1
                netDir = netDir + rightNorm;
            end
    
            % If all four toggles are off, netDir remains zero => no move
            if norm(netDir) > 1e-6
                netDir = app.moveStep * (netDir / norm(netDir));  % scale by step
                newCamPos = camPos + netDir;
                newCamTgt = camTgt + netDir;
                app.UIAxes3D.CameraPosition = newCamPos;
                app.UIAxes3D.CameraTarget   = newCamTgt;
            end
        end

        function updateCameraMotionTimer(app)
            % If ANY of the four buttons is pressed, we want the timer running.
            anyPressed = ...
                (app.TranslationAVButton.Value == 1) || ...
                (app.TranslationARButton.Value == 1) || ...
                (app.TranslationGAButton.Value == 1) || ...
                (app.TranslationDRButton.Value == 1);
    
            if anyPressed
                if strcmp(app.cameraMotionTimer.Running, 'off')
                    start(app.cameraMotionTimer);
                end
            else
                if strcmp(app.cameraMotionTimer.Running, 'on')
                    stop(app.cameraMotionTimer);
                end
            end
        end


        function updateDroneTable(app)
            nDrones = numel(app.swarm.Drones);
            data = cell(nDrones, 12);
            
            for i = 1:nDrones
                d = app.swarm.Drones{i};
                if(length(app.env.TargetsList) ==1) 
                    cible = app.env.TargetsList{end};
                else
                    cible = app.env.TargetsList{d.targetGroup};
                end
                
                data{i,1}  = num2str(d.ID);
                data{i,2}  = d.Name;
                data{i, 3} = strjoin(arrayfun(@(x) sprintf('%.1f', x), d.posState, 'UniformOutput', false), ',');
                data{i,4}  = cible.Name;
                
                if d.IsAlive == true
                    data{i,5}  = 'Opérationnel';
                else
                    data{i,5}  = 'Détruit';
                end

                if strcmp(d.phase, 'airborn')
                    if d.phase == false
                        data{i,6}  = 'targetlock';
                    else
                        data{i,6}  = 'waypointfollow';
                    end
                else
                    data{i,6}  = d.phase;
                end
                
                data{i,7}  = d.Type;
                data{i,8}  = strjoin(d.Model, ' ');
                data{i,9}  = num2str(d.AutonomyMins);  
                data{i,10} = num2str((d.remainingCapacity / d.NominalCapacity) * 100);
                data{i,11} = num2str(d.ReloadMins);    
                data{i,12} = num2str(d.chargeTime);
            end

            app.UIFlotteTable.Data = data;
        end

        %% MANQUE DATA 5
        function updateTargetTable(app)
            drones = app.swarm.Drones;
            nTargets = numel(app.env.TargetsList);
            data = cell(nTargets, 6);
            
            for i = 1:nTargets 
                t = app.env.TargetsList{i};
                data{i,1} = num2str(i);
                data{i,2} = t.Name;
                data{i,3} = sprintf('%.1f, %.1f, %.1f', t.Position);
                data{i,4} = t.Status;
                data{i,5} = '';      % Assigned Fleet (if applicable)
                data{i,6} = num2str(t.ObservabilityScore);
            end
        
            app.UICibleTable.Data = data;
        end
        
        function updateZoneTable(app)
            nZones = numel(app.env.ZonesList);
            data = cell(nZones, 8);
            
            for i = 1:nZones
                z = app.env.ZonesList{i};
                data{i,1} = num2str(i);
                data{i,2} = z.Name;
                data{i,3} = z.Type;
                data{i,4} = sprintf('%.1f, %.1f, %.1f', z.CenterPosition);
                data{i,5} = sprintf('%.1f, %.1f, %.1f', z.Dimensions);
                data{i,6} = num2str(z.TiltAngle);
                data{i,7} = z.Status;
                data{i,8} = num2str(z.ViolationTime);
            end
        
            app.UIZoneTable.Data = data;
        end
        
        function updateObstacleTable(app)
            nObstacles = numel(app.env.ObstaclesList);
            data = cell(nObstacles, 5);
            
            for i = 1:nObstacles
                o = app.env.ObstaclesList{i};
                data{i,1} = num2str(i);
                data{i,2} = sprintf('%.1f, %.1f, %.1f', o.CenterPosition);
                data{i,3} = sprintf('%.1f, %.1f, %.1f', o.Dimensions);
                data{i,4} = num2str(o.TiltAngle);
                data{i,5} = o.Status;
            end
        
            app.UIObstacleTable.Data = data;
        end


        function pauseSimulationTimer(app)
            if ~isempty(app.simTimer) && isvalid(app.simTimer)
                stop(app.simTimer);
            end
            app.simulationRunning = false;
            app.Lamp.Color = [1 0 0];
            addConsoleMsg(app, "Simulation en pause.");
        end

        function addConsoleMsg(app, message)
            timestamp = datetime("now");
            formattedMsg = sprintf('[%s] %s', timestamp, message);
        
            currentConsoleValue = app.Console.Value;
            if ischar(currentConsoleValue)
                currentConsoleValue = {currentConsoleValue};
            end

            app.Console.Value = [{formattedMsg}; currentConsoleValue];
        end


        function updateDroneDetails(app, dropdown, modelLabel, typeLabel, autonomyLabel, rechargeLabel)
            selectedName = dropdown.Value;
            idx = strcmp(app.UIFlotteTable.Data(:, 2), selectedName);
            if any(idx)
                modelLabel.Text = app.UIFlotteTable.Data{idx, 8};
                typeLabel.Text = app.UIFlotteTable.Data{idx, 7};
                autonomyLabel.Text = num2str(app.UIFlotteTable.Data{idx, 9});
                rechargeLabel.Text = num2str(app.UIFlotteTable.Data{idx, 11});
            end
        end
        
        function updateInfosModeles(app, dropdown, typeLabel, autonomyLabel, rechargeLabel)
            selectedModel = dropdown.Value;
            idx = strcmp(app.DroneModels.Model, selectedModel);
            if any(idx)
                typeLabel.Text = app.DroneModels.Type{idx};
                autonomyLabel.Text = num2str(app.DroneModels.AutonomyMins(idx));
                rechargeLabel.Text = num2str(app.DroneModels.ReloadMins(idx));
            else
                typeLabel.Text = '';
                autonomyLabel.Text = '';
                rechargeLabel.Text = '';
            end
        end
        
        function refreshDroneLabelsFromSwarm(app, dropdown, ...
                                             modelLabel, typeLabel, ...
                                             autonomyLabel, rechargeLabel)
            currentName = dropdown.Value;
            idx = find(cellfun(@(dr) strcmp(dr.Name, currentName), app.swarm.Drones));
            if isempty(idx)
                modelLabel.Text     = '';
                typeLabel.Text      = '';
                autonomyLabel.Text  = '';
                rechargeLabel.Text  = '';
                return;
            end
            d = app.swarm.Drones{idx};
        
            modelLabel.Text     = d.Model;
            typeLabel.Text      = d.Type;
            autonomyLabel.Text  = num2str(d.AutonomyMins);
            rechargeLabel.Text  = num2str(d.ReloadMins);
        end

        function suppressionDroneCallback(app, fig, dropdown)
            selectedName = dropdown.Value;
        
            idx = find(cellfun(@(dr) strcmp(dr.Name, selectedName), app.swarm.Drones));
            if isempty(idx)
                uialert(fig, ...
                    sprintf('Drone "%s" introuvable.', selectedName), ...
                    'Erreur');
                return;
            end
        
            app.swarm.Drones(idx) = [];
        
            updateDroneTable(app);
        
            close(fig);
        end
        
        function modificationDroneCallback(app, fig, dropdown, nameField)
            selectedName = dropdown.Value;
            newName      = strtrim(nameField.Value);
        
            if isempty(newName)
                uialert(fig, 'Nom invalide (vide).', 'Erreur');
                return;
            end
        
            % Find the old drone in the swarm
            idx = find(cellfun(@(dr) strcmp(dr.Name, selectedName), app.swarm.Drones));
            if isempty(idx)
                uialert(fig, ...
                    sprintf('Drone "%s" introuvable dans la swarm.', selectedName), ...
                    'Erreur');
                return;
            end
        
            % Check for duplicate name
            isDup = any(cellfun(@(dr) strcmp(dr.Name, newName), app.swarm.Drones));
            if isDup && ~strcmp(selectedName, newName)
                uialert(fig, 'Nom déjà existant.', 'Erreur');
                return;
            end
        
            % Perform the rename
            app.swarm.Drones{idx}.Name = newName;
        
            % Refresh the UI table
            updateDroneTable(app);
        
            % Close the small UI
            close(fig);
        end
        
        function ajoutDroneCallback(app, fig, nameField, modelDropdown, ...
                                  typeLabel, autonomyLabel, rechargeLabel)
            % Retrieve data from input fields
            newName       = strtrim(nameField.Value);
            selectedModel = modelDropdown.Value;
            selectedType  = strtrim(typeLabel.Text);
        
            % Basic validity checks
            if isempty(newName)
                uialert(fig, 'Nom invalide (vide).', 'Erreur');
                return;
            end
        
            % Look up the row in DroneModels
            modelRow = app.DroneModels(strcmp(app.DroneModels.Model, selectedModel), :);
            if isempty(modelRow)
                uialert(fig, ...
                    sprintf('Modèle "%s" introuvable dans DroneModels.', selectedModel), ...
                    'Erreur');
                return;
            end
        
            % Check if the new name is already used by an existing drone
            isDup = any(cellfun(@(dr) strcmp(dr.Name, newName), app.swarm.Drones));
            if isDup
                uialert(fig, 'Nom déjà existant.', 'Erreur');
                return;
            end
        
            % For demonstration, choose a default initial position
            initPos = [0 0 0];
        
            % Finally, add to the swarm
            % (app.swarm.addDrone() should be a method that expects
            %  addDrone(droneType, droneName, modelDataRow, initialPosition).)
            app.swarm.addDrone(selectedType, newName, modelRow, initPos);
        
            % Update the UI table to reflect the new swarm
            updateDroneTable(app);
        
            % Close the small UI
            close(fig);
        end


        % Manipulation de data - Cibles
        function updateTargetDetails(app, dropdown, coordLabel, statusLabel)
            selectedName = dropdown.Value;
            idx = strcmp(app.UICibleTable.Data(:, 2), selectedName);
            if any(idx)
                coordLabel.Text = app.UICibleTable.Data{idx, 3};
                statusLabel.Text = app.UICibleTable.Data{idx, 4};
            else
                coordLabel.Text = '';
                statusLabel.Text = '';
            end
        end

        function updateTargetFields(app, dropdown, newNameField, coordField, statusDropdown)
            selectedName = dropdown.Value;
            idx = strcmp(app.UICibleTable.Data(:, 2), selectedName);
            if any(idx)
                newNameField.Value = app.UICibleTable.Data{idx, 2};
                coordField.Value = app.UICibleTable.Data{idx, 3};
                statusDropdown.Value = app.UICibleTable.Data{idx, 4};
            else
                newNameField.Value = '';
                coordField.Value = '';
                statusDropdown.Value = '';
            end
        end

        function suppressionCibleCallback(app, fig, dropdown)
            nameToDelete = dropdown.Value;
            idx = findTargetIndexByName(app, nameToDelete);
            if isempty(idx)
                uialert(fig, sprintf('Cible "%s" introuvable.', nameToDelete), 'Erreur');
                return;
            end
    
            % Remove it from the environment’s list
            app.env.TargetsList(idx) = [];
    
            % Update UI
            updateTargetTable(app);
            close(fig);
        end

        function modificationCibleCallback(app, fig, nomDropdown, newNameField, coordField, statusDropdown)
            oldName   = nomDropdown.Value;
            newName   = strtrim(newNameField.Value);
            coordsStr = strtrim(coordField.Value);
            newStatus = statusDropdown.Value;
    
            % Find index of the old target
            idx = findTargetIndexByName(app, oldName);
            if isempty(idx)
                uialert(fig, sprintf('Cible "%s" introuvable.', oldName), 'Erreur');
                return;
            end
    
            tObj = app.env.TargetsList{idx};
    
            % If user wants to change the name
            if ~isempty(newName)
                % Check for duplication
                if ~strcmp(oldName, newName)
                    dupIdx = findTargetIndexByName(app, newName);
                    if ~isempty(dupIdx)
                        uialert(fig, 'Nom déjà utilisé par une autre cible.', 'Erreur');
                        return;
                    end
                end
                tObj.Name = newName;
            end
    
            % If user provided new coordinates
            if ~isempty(coordsStr)
                try
                    coordArray = str2num(coordsStr); %#ok<ST2NM>
                    if numel(coordArray) ~= 3
                        error('Format invalide');
                    end
                    tObj.Position = coordArray;
                catch
                    uialert(fig, 'Coordonnées invalides (X,Y,Z).', 'Erreur');
                    return;
                end
            end
    
            % If user selected a new status
            if ~isempty(newStatus)
                tObj.Status = newStatus;
            end
    
            % Refresh table and close
            updateTargetTable(app);
            close(fig);
        end

        function ajoutCibleCallback(app, fig, nameField, coordField, statusDropdown)
            % 1) Gather user input
            newName = strtrim(nameField.Value);
            coordsStr = strtrim(coordField.Value);
            newStatus = statusDropdown.Value;
    
            % 2) Validate name
            if isempty(newName)
                uialert(fig, 'Nom invalide (vide).', 'Erreur');
                return;
            end
            
            % 3) Parse coordinates string (X,Y,Z)
            try
                coordArray = str2num(coordsStr); %#ok<ST2NM>
                if numel(coordArray) ~= 3
                    error('Format invalide');
                end
            catch
                uialert(fig, 'Coordonnées invalides. Format requis: "X,Y,Z".', 'Erreur');
                return;
            end
    
            % 4) Check for duplicate name in environment
            idx = findTargetIndexByName(app, newName);
            if ~isempty(idx)
                uialert(fig, 'Une cible portant ce nom existe déjà.', 'Erreur');
                return;
            end
    
            % 5) Create and add Target to environment
            %    We'll assign an ObservabilityScore = 0 by default (or any value).
            newTarget = Target(newName, coordArray, newStatus, 0, app.env);
            app.env.addTarget(newTarget);
    
            % 6) Refresh table and close the window
            updateTargetTable(app);
            close(fig);
        end


        % Manipulation de data - Zones
        function updateZoneDetails(app, dropdown, typeLabel, coordLabel, sizeLabel, tiltLabel, statusLabel)
            selectedName = dropdown.Value;
            idx = strcmp(app.UIZoneTable.Data(:, 2), selectedName);
            if any(idx)
                typeLabel.Text = app.UIZoneTable.Data{idx, 3};
                coordLabel.Text = app.UIZoneTable.Data{idx, 4};
                sizeLabel.Text = num2str(app.UIZoneTable.Data{idx, 5});
                tiltLabel.Text = num2str(app.UIZoneTable.Data{idx, 6});
                statusLabel.Text = app.UIZoneTable.Data{idx, 7};
            else
                typeLabel.Text = '';
                coordLabel.Text = '';
                sizeLabel.Text = '';
                tiltLabel.Text = '';
                statusLabel.Text = '';
            end
        end

        function updateZoneFields(app, dropdown, newNameField, typeDropdown, coordField, sizeField, tiltField, statusDropdown)
            selectedName = dropdown.Value;
            idx = strcmp(app.UIZoneTable.Data(:, 2), selectedName);
            if any(idx)
                newNameField.Value = app.UIZoneTable.Data{idx, 2};
                typeDropdown.Value = app.UIZoneTable.Data{idx, 3};
                coordField.Value = app.UIZoneTable.Data{idx, 4};
                sizeField.Value = app.UIZoneTable.Data{idx, 5};
                tiltField.Value = app.UIZoneTable.Data{idx, 6};
                statusDropdown.Value = app.UIZoneTable.Data{idx, 7};
            else
                newNameField.Value = '';
                typeDropdown.Value = '';
                coordField.Value = '';
                sizeField.Value = '';
                tiltField.Value = '';
                statusDropdown.Value = '';
            end
        end

        function suppressionZoneCallback(app, fig, nameDropdown)
            nameToDelete = nameDropdown.Value;
            idx = findZoneIndexByName(app, nameToDelete);
            if isempty(idx)
                uialert(fig, sprintf('Zone "%s" introuvable.', nameToDelete), 'Erreur');
                return;
            end
    
            % Remove from environment
            app.env.ZonesList(idx) = [];
    
            updateZoneTable(app);
            close(fig);
        end

        function modificationZoneCallback(app, fig, nameDropdown, newNameField, typeDropdown, coordField, sizeField, tiltField, statusDropdown)
            oldName = nameDropdown.Value;
            idx = findZoneIndexByName(app, oldName);
            if isempty(idx)
                uialert(fig, sprintf('Zone "%s" introuvable.', oldName), 'Erreur');
                return;
            end
    
            zObj = app.env.ZonesList{idx};
    
            % Now apply changes from user input
            proposedName = strtrim(newNameField.Value);
            if ~isempty(proposedName) && ~strcmp(proposedName, oldName)
                % Check duplication
                if ~isempty(findZoneIndexByName(app, proposedName))
                    uialert(fig, 'Nom déjà utilisé.', 'Erreur');
                    return;
                end
                zObj.Name = proposedName;
            end
    
            % Type
            if ~isempty(typeDropdown.Value)
                zObj.Type = typeDropdown.Value;
            end
    
            % Coordinates
            coordsStr = strtrim(coordField.Value);
            if ~isempty(coordsStr)
                cArr = str2num(coordsStr); %#ok<ST2NM>
                if numel(cArr) == 3
                    zObj.CenterPosition = cArr;
                end
            end
    
            % Size
            if ~isempty(sizeField.Value)
                zObj.Dimensions = sizeField.Value;  % or parse
            end
    
            % Tilt
            if ~isempty(tiltField.Value)
                zObj.TiltAngle = tiltField.Value;
            end
    
            % Status
            if ~isempty(statusDropdown.Value)
                zObj.Status = statusDropdown.Value;
            end
    
            updateZoneTable(app);
            close(fig);
        end

        function ajoutZoneCallback(app, fig, nameField, typeDropdown, coordField, sizeField, tiltField, statusDropdown)
            % 1) Gather user inputs
            newName  = strtrim(nameField.Value);
            newType  = typeDropdown.Value;
            status   = statusDropdown.Value;
            coords   = strtrim(coordField.Value);
            dims     = str2num(sizeField.Value);  %#ok<ST2NM>
            tiltVal  = tiltField.Value;
    
            % 2) Validate name & check for duplicate
            if isempty(newName)
                uialert(fig, 'Nom invalide (vide).', 'Erreur');
                return;
            end
            idx = findZoneIndexByName(app, newName);
            if ~isempty(idx)
                uialert(fig, 'Une zone portant ce nom existe déjà.', 'Erreur');
                return;
            end
    
            % 3) Parse coordinates
            try
                coordArray = str2num(coords); %#ok<ST2NM>
                if numel(coordArray) ~= 3
                    error('Invalid format');
                end
            catch
                uialert(fig, 'Coordonnées invalides: X,Y,Z.', 'Erreur');
                return;
            end
    
            % 4) Build new Zone object
            % Suppose constructor is: Zone(name, type, category, center, dims, tilt, status, env)
            % If your "type" is actually category, adjust as needed.
            z = Zone(newName, newType, newType, coordArray, dims, tiltVal, status, app.env);
    
            % 5) Add to env.ZonesList
            app.env.addZone(z);
    
            % 6) Update table & close
            updateZoneTable(app);
            close(fig);
        end


        % Manipulation de data - Obstacles
        function updateObstacleDetails(app, dropdown, coordLabel, sizeLabel, tiltLabel)
            selectedNum = dropdown.Value;
            idx = strcmp(app.UIObstacleTable.Data(:, 1), selectedNum);
            if any(idx)
                coordLabel.Text = app.UIObstacleTable.Data{idx, 2};
                sizeLabel.Text = num2str(app.UIObstacleTable.Data{idx, 3});
                tiltLabel.Text = num2str(app.UIObstacleTable.Data{idx, 4});
            else
                coordLabel.Text = '';
                sizeLabel.Text = '';
                tiltLabel.Text = '';
            end
        end

        function updateObstacleFields(app, dropdown, coordField, sizeField, tiltField, statusDropdown)
            % Ensure dropdown has a valid value
            if isempty(dropdown.Value) || isempty(app.UIObstacleTable.Data)
                coordField.Value = '';
                sizeField.Value = '';
                tiltField.Value = '';
                statusDropdown.Value = '';
                return;
            end
        
            selectedNum = dropdown.Value;
            idx = strcmp(app.UIObstacleTable.Data(:, 1), selectedNum);
        
            % Update fields if the obstacle exists
            if any(idx)
                coordField.Value = app.UIObstacleTable.Data{idx, 2};
                sizeField.Value = app.UIObstacleTable.Data{idx, 3};
                tiltField.Value = app.UIObstacleTable.Data{idx, 4};
                statusDropdown.Value = app.UIObstacleTable.Data{idx, 5};
            else
                coordField.Value = '';
                sizeField.Value = '';
                tiltField.Value = '';
                statusDropdown.Value = '';
            end
        end

        function suppressionObstacleCallback(app, fig, nameDropdown)
            nameToDelete = nameDropdown.Value;
            idx = findObstacleIndexByName(app, nameToDelete);
            if isempty(idx)
                uialert(fig, sprintf('Obstacle "%s" introuvable.', nameToDelete), 'Erreur');
                return;
            end
    
            app.env.ObstaclesList(idx) = [];
            updateObstacleTable(app);
            close(fig);
        end

        function modificationObstacleCallback(app, fig, numDropdown, coordField, sizeField, tiltField, statusDropdown)
            selectedNum = numDropdown.Value;
            newCoord = coordField.Value;
            newSize = sizeField.Value;
            newTilt = tiltField.Value;
            newStatus = statusDropdown.Value;
        
            % Validate inputs
            if isempty(newCoord) || isempty(newSize) || isempty(newTilt) || isempty(newStatus)
                uialert(fig, 'Données invalides ou incomplètes.', 'Erreur');
                return;
            end
        
            % Update the table
            idx = strcmp(app.UIObstacleTable.Data(:, 1), selectedNum);
            if any(idx)
                app.UIObstacleTable.Data{idx, 2} = newCoord;
                app.UIObstacleTable.Data{idx, 3} = newSize;
                app.UIObstacleTable.Data{idx, 4} = newTilt;
                app.UIObstacleTable.Data{idx, 5} = newStatus;
                close(fig);
            else
                uialert(fig, 'Obstacle non trouvé.', 'Erreur');
            end
        end

        function ajoutObstacleCallback(app, fig, nameField, coordField, sizeField, tiltField, statusDropdown)
            newName = strtrim(nameField.Value);
            if isempty(newName)
                uialert(fig, 'Nom invalide (vide).', 'Erreur');
                return;
            end
    
            % Check duplicates
            idx = findObstacleIndexByName(app, newName);
            if ~isempty(idx)
                uialert(fig, 'Nom déjà existant.', 'Erreur');
                return;
            end
    
            coordsStr = strtrim(coordField.Value);
            cArr = str2num(coordsStr); %#ok<ST2NM>
            if numel(cArr) ~= 3
                uialert(fig, 'Coordonnées invalides.', 'Erreur');
                return;
            end
            
            dimsVal = sizeField.Value;  % etc.
            tiltVal = tiltField.Value;
            statVal = statusDropdown.Value;
    
            % Construct new obstacle
            newObst = Obstacle(newName, 'typeX', 'catX', cArr, dimsVal, tiltVal, statVal, app.env);
            % Adjust as needed for your constructor’s parameters
    
            % Add it
            app.env.addObstacle(newObst);
    
            updateObstacleTable(app);
            close(fig);
        end       
    end
    
    methods (Access = private)
        
        function InitTargetPanel(app)
             targetNames = {};
            
            for i=1:size(app.env.TargetsList, 2)
                targetNames{end+1} = app.env.TargetsList{i}.Name;
            end
            app.TargetDropDown.Items = targetNames;

            app.TargetSliderX.Limits = [-200, 200];
            app.TargetSliderY.Limits = [-200, 200];
            app.TargetSliderZ.Limits = [0, 100];

        end
        
        function results = OnTargetCoorChanged(app, value, coor)
            currentTargetName = app.TargetDropDown.Value;
            currentTargetNumber = find(strcmp(app.TargetDropDown.Items, currentTargetName));

            newPosition = app.env.TargetsList{currentTargetNumber}.Position;
            newPosition(coor) = value;
            app.env.TargetsList{currentTargetNumber}.Position = newPosition;

            RTPlot2_OnTargetPosChange(app);

            UpdateTargetTable(app, currentTargetNumber);
            
        end
        
        function UpdateTargetTable(app, targetNumber)
            table = app.UICibleTable;
            table.Data{targetNumber, 3} = sprintf('%.1f, %.1f, %.1f', app.env.TargetsList{targetNumber}.Position(1), app.env.TargetsList{targetNumber}.Position(2), app.env.TargetsList{targetNumber}.Position(3));
        end
        
        function results = UpdateTargetTab(app)
            currentTargetName = app.TargetDropDown.Value;
            currentTargetNumber = find(strcmp(app.TargetDropDown.Items, currentTargetName));

            value_x = app.env.TargetsList{currentTargetNumber}.Position(1);
            value_y = app.env.TargetsList{currentTargetNumber}.Position(2);
            value_z = app.env.TargetsList{currentTargetNumber}.Position(3);

            app.EditTargetFieldX.Value = num2str(value_x);
            app.EditTargetFieldY.Value = num2str(value_y);
            app.EditTargetFieldZ.Value = num2str(value_z);

            app.TargetSliderX.Value = value_x;
            app.TargetSliderY.Value = value_y;
            app.TargetSliderZ.Value = value_z;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc

            app.IHMProjetESTACADC2025ContrledunEssaimdeDronesUIFigure.WindowState = 'maximized';

            app.Console.Value         = {''};
            app.tempscouleEditField.Value = "0";
            app.simulationTime        = 0;
            app.DronesdploysEditField.Value    = 0;
            app.DronesnondploysEditField.Value = 0;
            app.DronesperdusEditField.Value    = 0;

            app.UICibleTable.Data  = {};  
            app.UIFlotteTable.Data = {};
            app.UIZoneTable.Data   = {};          

            app.cameraMotionTimer = timer( ...
                'ExecutionMode','fixedSpacing', ...
                'Period',0.05, ...        % 20 Hz movement updates
                'TimerFcn',@(~,~)app.moveCameraContinuous(), ...
                'BusyMode','drop');

            setupSimulation(app);

            InitTargetPanel(app);

            UpdateTargetTab(app);
        
            % Log initialization completion
            addConsoleMsg(app, "Initialisation de l'interface terminée. Prêt à démarrer.");
        end

        % Button pushed function: DmarrerButton
        function DmarrerButtonPushed(app, event)
            if ~app.simulationRunning
                % Initialize or re-initialize the simulation
                % setupSimulation(app);
                startSimulationTimer(app);
            elseif app.simulationPaused
                % If it's paused, just unpause it
                app.simulationPaused = false;
                app.Lamp.Color = [0 1 0];
                addConsoleMsg(app, "Simulation redemarrée.");
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            if app.simulationRunning
                pauseSimulationTimer(app);
            end
            % Re-initialize everything
            setupSimulation(app);
            app.Lamp.Color = [1 0 0];
            cla(app.UIAxes3D,"reset");
            addConsoleMsg(app, "Simulation remise à zero.");
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            if app.simulationRunning && ~app.simulationPaused
                app.simulationPaused = true;
                app.Lamp.Color = [1 1 0]; % Yellow
                addConsoleMsg(app, "Simulation paused.");
            elseif app.simulationRunning && app.simulationPaused
                % If paused, pressing Pause again could resume or do nothing
                app.simulationPaused = false;
                app.Lamp.Color = [0 1 0];
                addConsoleMsg(app, "Simulation resumed.");
            end
        end

        % Button pushed function: SuppressionDroneButton
        function SuppressionDroneButtonPushed(app, event)
            supprFig = uifigure('Name', 'Suppression Drone', ...
                                'Position', [100, 100, 400, 250]);
        
            % Gather the names from the swarm
            droneNames = cellfun(@(dr) dr.Name, app.swarm.Drones, 'UniformOutput', false);
        
            % Dropdown menu to select which drone to delete
            uilabel(supprFig, 'Position', [20, 190, 100, 22], 'Text', 'Nom:');
            nomDropdown = uidropdown(supprFig, ...
                'Position', [130, 190, 200, 22], ...
                'Items', droneNames);
        
            % Display a few details
            uilabel(supprFig, 'Position', [20, 140, 100, 22], 'Text', 'Modèle:');
            modelLabel = uilabel(supprFig, 'Position', [130, 140, 200, 22]);
        
            uilabel(supprFig, 'Position', [20, 100, 100, 22], 'Text', 'Type:');
            typeLabel = uilabel(supprFig, 'Position', [130, 100, 200, 22]);
        
            uilabel(supprFig, 'Position', [20, 60, 200, 22], 'Text', 'Autonomie (min):');
            autonomyLabel = uilabel(supprFig, 'Position', [230, 60, 100, 22]);
        
            uilabel(supprFig, 'Position', [20, 30, 200, 22], 'Text', 'Recharge (min):');
            rechargeLabel = uilabel(supprFig, 'Position', [230, 30, 100, 22]);
        
            % Update the details when dropdown changes
            nomDropdown.ValueChangedFcn = @(~, ~) ...
                 refreshDroneLabelsFromSwarm(app, nomDropdown, ...
                                             modelLabel, typeLabel, ...
                                             autonomyLabel, rechargeLabel);
            refreshDroneLabelsFromSwarm(app, nomDropdown, ...
                                        modelLabel, typeLabel, ...
                                        autonomyLabel, rechargeLabel);
        
            % OK button to confirm deletion
            okButton = uibutton(supprFig, 'Text', 'OK', ...
                                'Position', [150, 10, 100, 30]);
            okButton.ButtonPushedFcn = @(~, ~) suppressionDroneCallback(app, supprFig, nomDropdown);
        end

        % Button pushed function: ModificationDroneButton
        function ModificationDroneButtonPushed(app, event)
            % Create a new figure for modifying a drone
            modifFig = uifigure('Name', 'Modification Drone', ...
                                'Position', [100, 100, 400, 300]);
        
            % Gather the names from the swarm, not from the table
            droneNames = cellfun(@(dr) dr.Name, app.swarm.Drones, 'UniformOutput', false);
        
            % Dropdown menu to select existing drone
            uilabel(modifFig, 'Position', [20, 230, 100, 22], 'Text', 'Nom:');
            nomDropdown = uidropdown(modifFig, ...
                'Position', [130, 230, 200, 22], ...
                'Items', droneNames);
        
            % (Optional) Show some info about that drone’s model, type, autonomy, etc.
            % We can reuse "updateDroneDetails" if it’s purely reading from the table,
            % or write a new function that reads from the swarm. Below we just show
            % the same labels as an example:
            uilabel(modifFig, 'Position', [20, 190, 100, 22], 'Text', 'Modèle:');
            modelLabel = uilabel(modifFig, 'Position', [130, 190, 200, 22]);
        
            uilabel(modifFig, 'Position', [20, 150, 150, 22], 'Text', 'Type:');
            typeLabel = uilabel(modifFig, 'Position', [130, 150, 200, 22]);
        
            uilabel(modifFig, 'Position', [20, 110, 200, 22], 'Text', 'Autonomie:');
            autonomyLabel = uilabel(modifFig, 'Position', [230, 110, 100, 22]);
        
            uilabel(modifFig, 'Position', [20, 70, 200, 22], 'Text', 'Recharge:');
            rechargeLabel = uilabel(modifFig, 'Position', [230, 70, 100, 22]);
        
            % Text field for the new name
            uilabel(modifFig, 'Position', [20, 30, 150, 22], 'Text', 'Nouveau Nom:');
            newNameField = uieditfield(modifFig, ...
                'Position', [130, 30, 200, 22]);
        
            % When the user picks a different drone from the dropdown,
            % you can fill in the labels with actual info from the swarm.
            nomDropdown.ValueChangedFcn = @(~,~) ...
               refreshDroneLabelsFromSwarm(app, nomDropdown, ...
                                           modelLabel, typeLabel, ...
                                           autonomyLabel, rechargeLabel);
            % Initialize once:
            refreshDroneLabelsFromSwarm(app, nomDropdown, ...
                                        modelLabel, typeLabel, ...
                                        autonomyLabel, rechargeLabel);
        
            % OK button to confirm
            okButton = uibutton(modifFig, 'Text', 'OK', 'Position', [150, 10, 100, 30]);
            okButton.ButtonPushedFcn = @(~, ~) ...
                        modificationDroneCallback(app, modifFig, nomDropdown, newNameField);
        end

        % Button pushed function: AjoutDroneButton
        function AjoutDroneButtonPushed(app, event)
            % Create a new figure for adding a drone
            models = app.DroneModels;
            numDrones = numel(app.swarm.Drones);   % we now look at app.swarm
            ajoutFig = uifigure('Name', ...
                ['Ajout Drone n°', num2str(numDrones + 1)], ...
                'Position', [100, 100, 400, 300]);
        
            % Input field for drone name
            uilabel(ajoutFig, 'Position', [20, 230, 150, 22], 'Text', 'Nom:');
            nameField = uieditfield(ajoutFig, 'Position', [130, 230, 200, 22]);
        
            % Dropdown for drone models
            uilabel(ajoutFig, 'Position', [20, 190, 150, 22], 'Text', 'Modèle:');
            modelDropdown = uidropdown(ajoutFig, ...
                'Position', [130, 190, 200, 22], ...
                'Items', models.Model);
        
            % Labels for additional details
            uilabel(ajoutFig, 'Position', [20, 150, 100, 22], 'Text', 'Type:');
            typeLabel = uilabel(ajoutFig, 'Position', [130, 150, 200, 22]);
        
            uilabel(ajoutFig, 'Position', [20, 110, 200, 22], ...
                'Text', 'Autonomie (minutes):');
            autonomyLabel = uilabel(ajoutFig, 'Position', [230, 110, 100, 22]);
        
            uilabel(ajoutFig, 'Position', [20, 70, 200, 22], ...
                'Text', 'Recharge (minutes):');
            rechargeLabel = uilabel(ajoutFig, 'Position', [230, 70, 100, 22]);
        
            % Update these labels when the user selects a different Model
            modelDropdown.ValueChangedFcn = ...
                @(src, ~) updateInfosModeles(app, src, typeLabel, autonomyLabel, rechargeLabel);
        
            % Initialize with the first model’s info (if not empty):
            if ~isempty(models.Model)
                updateInfosModeles(app, modelDropdown, typeLabel, autonomyLabel, rechargeLabel);
            end
        
            % OK button to confirm addition
            okButton = uibutton(ajoutFig, 'Text', 'OK', 'Position', [150, 10, 100, 30]);
            okButton.ButtonPushedFcn = @(~, ~) ajoutDroneCallback(app, ajoutFig, ...
                                                    nameField, modelDropdown, ...
                                                    typeLabel, autonomyLabel, rechargeLabel);
        end

        % Button pushed function: AjoutCibleButton
        function AjoutCibleButtonPushed(app, event)
            % Create a new figure for adding a Target
            ajoutFig = uifigure('Name', 'Ajout Cible', ...
                                'Position', [100, 100, 400, 300]);
    
            uilabel(ajoutFig, 'Text', 'Nom:', ...
                'Position', [20 230 100 22]);
            nameField = uieditfield(ajoutFig, ...
                'Position', [130 230 200 22]);
    
            uilabel(ajoutFig, 'Text', 'Coordonnées (X,Y,Z):', ...
                'Position', [20 190 120 22]);
            coordField = uieditfield(ajoutFig, ...
                'Position', [130 190 200 22]);
    
            uilabel(ajoutFig, 'Text', 'Statut:', ...
                'Position', [20 150 100 22]);
            statusDropdown = uidropdown(ajoutFig, ...
                'Items', {'actif','inactif'}, ...
                'Position', [130 150 200 22]);
    
            okButton = uibutton(ajoutFig, 'Text', 'OK', ...
                'Position', [150 40 100 30], ...
                'ButtonPushedFcn', @(~,~) ajoutCibleCallback(app, ajoutFig, nameField, coordField, statusDropdown));
        end

        % Button pushed function: ModificationCibleButton
        function ModificationCibleButtonPushed(app, event)
            modifFig = uifigure('Name', 'Modification Cible', ...
                                'Position', [100, 100, 400, 400]);

            % Gather all target names from environment
            targetNames = cellfun(@(t) t.Name, app.env.TargetsList, ...
                                  'UniformOutput', false);
            
            uilabel(modifFig, 'Text', 'Nom actuel:', ...
                'Position', [20 330 100 22]);
            nomDropdown = uidropdown(modifFig, ...
                'Position', [130 330 200 22], ...
                'Items', targetNames);
    
            uilabel(modifFig, 'Text', 'Nouveau Nom:', ...
                'Position', [20 290 100 22]);
            newNameField = uieditfield(modifFig, ...
                'Position', [130 290 200 22]);
    
            uilabel(modifFig, 'Text', 'Coordonnées (X,Y,Z):', ...
                'Position', [20 250 130 22]);
            coordField = uieditfield(modifFig, ...
                'Position', [150 250 180 22]);
    
            uilabel(modifFig, 'Text', 'Statut:', ...
                'Position', [20 210 100 22]);
            statusDropdown = uidropdown(modifFig, ...
                'Items', {'actif','inactif'}, ...
                'Position', [130 210 180 22]);
    
            okButton = uibutton(modifFig, 'Text', 'OK', ...
                'Position', [150 50 100 30], ...
                'ButtonPushedFcn', @(~,~) modificationCibleCallback(app, modifFig, nomDropdown, newNameField, coordField, statusDropdown));
        end

        % Button pushed function: SuppressionCibleButton
        function SuppressionCibleButtonPushed(app, event)
            supprFig = uifigure('Name', 'Suppression Cible', ...
                                'Position', [100, 100, 400, 250]);
    
            targetNames = cellfun(@(t) t.Name, app.env.TargetsList, ...
                                  'UniformOutput', false);
    
            uilabel(supprFig, 'Text', 'Nom:', ...
                'Position', [20 190 100 22]);
            nomDropdown = uidropdown(supprFig, ...
                'Position', [130 190 200 22], ...
                'Items', targetNames);
    
            okButton = uibutton(supprFig, 'Text', 'OK', ...
                'Position', [150 20 100 30], ...
                'ButtonPushedFcn', @(~,~) suppressionCibleCallback(app, supprFig, nomDropdown));
        end

        % Button pushed function: AjoutZoneButton
        function AjoutZoneButtonPushed(app, event)
            % Create a new figure for adding a zone
            numZones = size(app.UIZoneTable.Data, 1);
            ajoutFig = uifigure('Name', ['Ajout Zone n°', num2str(numZones + 1)], 'Position', [100, 100, 400, 400]);
        
            % Input field for zone name
            uilabel(ajoutFig, 'Position', [20, 330, 150, 22], 'Text', 'Nom:');
            nameField = uieditfield(ajoutFig, 'Position', [130, 330, 200, 22]);
        
            % Dropdown for zone type
            uilabel(ajoutFig, 'Position', [20, 290, 150, 22], 'Text', 'Type:');
            typeDropdown = uidropdown(ajoutFig, 'Position', [130, 290, 200, 22], 'Items', {'allied', 'prohibited', 'other'});
        
            % Input field for coordinates
            uilabel(ajoutFig, 'Position', [20, 250, 150, 22], 'Text', 'Coordonnées (X,Y,Z):');
            coordField = uieditfield(ajoutFig, 'Position', [130, 250, 200, 22]);
        
            % Input field for size
            uilabel(ajoutFig, 'Position', [20, 210, 150, 22], 'Text', 'Taille (m):');
            sizeField = uieditfield(ajoutFig, 'numeric', 'Position', [130, 210, 200, 22]);
        
            % Input field for tilt angle
            uilabel(ajoutFig, 'Position', [20, 170, 150, 22], 'Text', 'Orientation (°):');
            tiltField = uieditfield(ajoutFig, 'numeric', 'Position', [130, 170, 200, 22]);
        
            % Dropdown for status
            uilabel(ajoutFig, 'Position', [20, 130, 150, 22], 'Text', 'Statut:');
            statusDropdown = uidropdown(ajoutFig, 'Position', [130, 130, 200, 22], 'Items', {'actif', 'inactif'});
        
            % OK button to confirm addition
            okButton = uibutton(ajoutFig, 'Text', 'OK', 'Position', [150, 50, 100, 30]);
            okButton.ButtonPushedFcn = @(~, ~) ajoutZoneCallback(app, ajoutFig, nameField, typeDropdown, coordField, sizeField, tiltField, statusDropdown);
        end

        % Button pushed function: ModificationZoneButton
        function ModificationZoneButtonPushed(app, event)
            % Create a new figure for modifying a zone
            modifFig = uifigure('Name', 'Modification Zone', 'Position', [100, 100, 400, 400]);
        
            % Dropdown menu to select existing zone
            uilabel(modifFig, 'Position', [20, 330, 150, 22], 'Text', 'Nom:');
            nameDropdown = uidropdown(modifFig, 'Position', [130, 330, 200, 22], 'Items', app.UIZoneTable.Data(:, 2));
        
            % Input fields for modifying data
            uilabel(modifFig, 'Position', [20, 290, 150, 22], 'Text', 'Nouveau Nom:');
            newNameField = uieditfield(modifFig, 'Position', [130, 290, 200, 22]);
        
            uilabel(modifFig, 'Position', [20, 250, 150, 22], 'Text', 'Type:');
            typeDropdown = uidropdown(modifFig, 'Position', [130, 250, 200, 22], 'Items', {'allied', 'prohibited', 'other'});
        
            uilabel(modifFig, 'Position', [20, 210, 150, 22], 'Text', 'Coordonnées (X,Y,Z):');
            coordField = uieditfield(modifFig, 'Position', [130, 210, 200, 22]);
        
            uilabel(modifFig, 'Position', [20, 170, 150, 22], 'Text', 'Taille (m):');
            sizeField = uieditfield(modifFig, 'numeric', 'Position', [130, 170, 200, 22]);
        
            uilabel(modifFig, 'Position', [20, 130, 150, 22], 'Text', 'Orientation (°):');
            tiltField = uieditfield(modifFig, 'numeric', 'Position', [130, 130, 200, 22]);
        
            uilabel(modifFig, 'Position', [20, 90, 150, 22], 'Text', 'Statut:');
            statusDropdown = uidropdown(modifFig, 'Position', [130, 90, 200, 22], 'Items', {'actif', 'inactif'});
        
            % OK button to confirm modification
            okButton = uibutton(modifFig, 'Text', 'OK', 'Position', [150, 10, 100, 30]);
            okButton.ButtonPushedFcn = @(~, ~) modificationZoneCallback(app, modifFig, nameDropdown, newNameField, typeDropdown, coordField, sizeField, tiltField, statusDropdown);
        
            % Update displayed details when a zone is selected
            nameDropdown.ValueChangedFcn = @(~, ~) updateZoneFields(app, nameDropdown, newNameField, typeDropdown, coordField, sizeField, tiltField, statusDropdown);
            updateZoneFields(app, nameDropdown, newNameField, typeDropdown, coordField, sizeField, tiltField, statusDropdown);
        end

        % Button pushed function: SuppressionZoneButton
        function SuppressionZoneButtonPushed(app, event)
            % Create a new figure for deleting a zone
            supprFig = uifigure('Name', 'Suppression Zone', 'Position', [100, 100, 400, 300]);
        
            % Dropdown menu to select existing zone
            uilabel(supprFig, 'Position', [20, 230, 150, 22], 'Text', 'Nom:');
            nameDropdown = uidropdown(supprFig, 'Position', [130, 230, 200, 22], 'Items', app.UIZoneTable.Data(:, 2));
        
            % Display zone details
            uilabel(supprFig, 'Position', [20, 190, 150, 22], 'Text', 'Type:');
            typeLabel = uilabel(supprFig, 'Position', [130, 190, 200, 22]);
        
            uilabel(supprFig, 'Position', [20, 150, 150, 22], 'Text', 'Coordonnées:');
            coordLabel = uilabel(supprFig, 'Position', [130, 150, 200, 22]);
        
            uilabel(supprFig, 'Position', [20, 110, 150, 22], 'Text', 'Taille (m):');
            sizeLabel = uilabel(supprFig, 'Position', [130, 110, 200, 22]);
        
            uilabel(supprFig, 'Position', [20, 70, 150, 22], 'Text', 'Orientation (°):');
            tiltLabel = uilabel(supprFig, 'Position', [130, 70, 200, 22]);
        
            % OK button to confirm deletion
            okButton = uibutton(supprFig, 'Text', 'OK', 'Position', [150, 10, 100, 30]);
            okButton.ButtonPushedFcn = @(~, ~) suppressionZoneCallback(app, supprFig, nameDropdown);
        
            % Update displayed details when a zone is selected
            nameDropdown.ValueChangedFcn = @(~, ~) updateZoneDetails(app, nameDropdown, typeLabel, coordLabel, sizeLabel, tiltLabel, []);
            updateZoneDetails(app, nameDropdown, typeLabel, coordLabel, sizeLabel, tiltLabel, []);
        end

        % Button pushed function: AjoutObstacleButton
        function AjoutObstacleButtonPushed(app, event)
            % Create a new figure for adding an obstacle
            numObstacles = size(app.UIObstacleTable.Data, 1);
            ajoutFig = uifigure('Name', ['Ajout Obstacle n°', num2str(numObstacles + 1)], 'Position', [100, 100, 400, 300]);
        
            % Input field for obstacle coordinates
            uilabel(ajoutFig, 'Position', [20, 230, 150, 22], 'Text', 'Coordonnées (X,Y,Z):');
            coordField = uieditfield(ajoutFig, 'Position', [180, 230, 200, 22]);
        
            % Input field for obstacle size
            uilabel(ajoutFig, 'Position', [20, 190, 150, 22], 'Text', 'Taille (m):');
            sizeField = uieditfield(ajoutFig, 'numeric', 'Position', [180, 190, 200, 22]);
        
            % Input field for obstacle orientation
            uilabel(ajoutFig, 'Position', [20, 150, 150, 22], 'Text', 'Orientation (°):');
            tiltField = uieditfield(ajoutFig, 'numeric', 'Position', [180, 150, 200, 22]);
        
            % Dropdown for obstacle status
            uilabel(ajoutFig, 'Position', [20, 110, 150, 22], 'Text', 'Statut:');
            statusDropdown = uidropdown(ajoutFig, 'Position', [180, 110, 200, 22], 'Items', {'actif', 'inactif'});
        
            % OK button to confirm addition
            okButton = uibutton(ajoutFig, 'Text', 'OK', 'Position', [150, 50, 100, 30]);
            okButton.ButtonPushedFcn = @(~, ~) ajoutObstacleCallback(app, ajoutFig, coordField, sizeField, tiltField, statusDropdown);
        end

        % Button pushed function: ModificationObstacleButton
        function ModificationObstacleButtonPushed(app, event)
            % Create a new figure for modifying an obstacle
            modifFig = uifigure('Name', 'Modification Obstacle', 'Position', [100, 100, 400, 400]);
        
            % Check if there are obstacles in the table
            if isempty(app.UIObstacleTable.Data)
                uialert(modifFig, 'Aucun obstacle à modifier.', 'Erreur');
                return;
            end
        
            % Dropdown menu to select existing obstacle
            uilabel(modifFig, 'Position', [20, 330, 150, 22], 'Text', 'N°:');
            obstacleNumbers = string(app.UIObstacleTable.Data(:, 1));
            numDropdown = uidropdown(modifFig, ...
                'Position', [130, 330, 200, 22], ...
                'Items', obstacleNumbers);
        
            % Set the dropdown value to the first item if not empty
            if ~isempty(obstacleNumbers)
                numDropdown.Value = obstacleNumbers(1);
            end
        
            % Input fields for modifying data
            uilabel(modifFig, 'Position', [20, 290, 150, 22], 'Text', 'Coordonnées (X,Y,Z):');
            coordField = uieditfield(modifFig, 'Position', [130, 290, 200, 22]);
        
            uilabel(modifFig, 'Position', [20, 250, 150, 22], 'Text', 'Taille (m):');
            sizeField = uieditfield(modifFig, 'numeric', 'Position', [130, 250, 200, 22]);
        
            uilabel(modifFig, 'Position', [20, 210, 150, 22], 'Text', 'Orientation (°):');
            tiltField = uieditfield(modifFig, 'numeric', 'Position', [130, 210, 200, 22]);
        
            uilabel(modifFig, 'Position', [20, 170, 150, 22], 'Text', 'Statut:');
            statusDropdown = uidropdown(modifFig, 'Position', [130, 170, 200, 22], 'Items', {'actif', 'inactif'});
        
            % OK button to confirm modification
            okButton = uibutton(modifFig, 'Text', 'OK', 'Position', [150, 50, 100, 30]);
            okButton.ButtonPushedFcn = @(~, ~) modificationObstacleCallback(app, modifFig, numDropdown, coordField, sizeField, tiltField, statusDropdown);
        
            % Update displayed details when an obstacle is selected
            numDropdown.ValueChangedFcn = @(~, ~) updateObstacleFields(app, numDropdown, coordField, sizeField, tiltField, statusDropdown);
            updateObstacleFields(app, numDropdown, coordField, sizeField, tiltField, statusDropdown);
        end

        % Button pushed function: SuppressionObstacleButton
        function SuppressionObstacleButtonPushed(app, event)
            % Create a new figure for deleting an obstacle
            supprFig = uifigure('Name', 'Suppression Obstacle', 'Position', [100, 100, 400, 250]);
        
            % Dropdown menu to select existing obstacle
            uilabel(supprFig, 'Position', [20, 150, 150, 22], 'Text', 'N°:');
            numDropdown = uidropdown(supprFig, 'Position', [180, 150, 200, 22], 'Items', string(app.UIObstacleTable.Data(:, 1)));
        
            % Display obstacle details
            uilabel(supprFig, 'Position', [20, 110, 150, 22], 'Text', 'Coordonnées:');
            coordLabel = uilabel(supprFig, 'Position', [180, 110, 200, 22]);
        
            uilabel(supprFig, 'Position', [20, 70, 150, 22], 'Text', 'Taille (m):');
            sizeLabel = uilabel(supprFig, 'Position', [180, 70, 200, 22]);
        
            uilabel(supprFig, 'Position', [20, 30, 150, 22], 'Text', 'Orientation (°):');
            tiltLabel = uilabel(supprFig, 'Position', [180, 30, 200, 22]);
        
            % OK button to confirm deletion
            okButton = uibutton(supprFig, 'Text', 'OK', 'Position', [150, 10, 100, 30]);
            okButton.ButtonPushedFcn = @(~, ~) suppressionObstacleCallback(app, supprFig, numDropdown);
        
            % Update displayed details when an obstacle is selected
            numDropdown.ValueChangedFcn = @(~, ~) updateObstacleDetails(app, numDropdown, coordLabel, sizeLabel, tiltLabel);
            updateObstacleDetails(app, numDropdown, coordLabel, sizeLabel, tiltLabel);
        end

        % Value changed function: TranslationAVButton
        function TranslationAVButtonValueChanged(app, event)
            updateCameraMotionTimer(app);
        end

        % Value changed function: TranslationARButton
        function TranslationARButtonValueChanged(app, event)
            updateCameraMotionTimer(app);
        end

        % Value changed function: TranslationGAButton
        function TranslationGAButtonValueChanged(app, event)
            updateCameraMotionTimer(app);
        end

        % Value changed function: TranslationDRButton
        function TranslationDRButtonValueChanged(app, event)
            updateCameraMotionTimer(app);
        end

        % Value changed function: EditTargetFieldX
        function EditTargetFieldXValueChanged(app, event)
            value = app.EditTargetFieldX.Value;
            OnTargetCoorChanged(app, str2num(value), 1);

            
        end

        % Value changed function: EditTargetFieldY
        function EditTargetFieldYValueChanged(app, event)
            value = app.EditTargetFieldY.Value;
            OnTargetCoorChanged(app, str2num(value), 2);
            
        end

        % Value changed function: EditTargetFieldZ
        function EditTargetFieldZValueChanged(app, event)
            value = app.EditTargetFieldZ.Value;
            OnTargetCoorChanged(app, str2num(value), 3);
            
        end

        % Value changed function: TargetSliderX
        function TargetSliderXValueChanged(app, event)
            value = app.TargetSliderX.Value;
            OnTargetCoorChanged(app, value, 1);
        end

        % Value changed function: TargetSliderY
        function TargetSliderYValueChanged(app, event)
            value = app.TargetSliderY.Value;
            OnTargetCoorChanged(app, value, 2);
        end

        % Value changed function: TargetSliderZ
        function TargetSliderZValueChanged(app, event)
            value = app.TargetSliderZ.Value;
            OnTargetCoorChanged(app, value, 3);
        end

        % Value changed function: TargetDropDown
        function TargetDropDownValueChanged(app, event)
            UpdateTargetTab(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.IHMProjetESTACADC2025ContrledunEssaimdeDronesUIFigure = uifigure('Visible', 'off');
            app.IHMProjetESTACADC2025ContrledunEssaimdeDronesUIFigure.Name = 'IHM Projet ESTACA D&C 2025 - Contrôle d''un Essaim de Drones';
            app.IHMProjetESTACADC2025ContrledunEssaimdeDronesUIFigure.Icon = fullfile(pathToMLAPP, 'graphique', 'logo.png');
            app.UIAxes3D = uiaxes(app.IHMProjetESTACADC2025ContrledunEssaimdeDronesUIFigure);
            title(app.UIAxes3D, 'Rendu en Temps Réel')
            xlabel(app.UIAxes3D, 'X')
            ylabel(app.UIAxes3D, 'Y')
            zlabel(app.UIAxes3D, 'Z')
            app.TabGroup = uitabgroup(app.IHMProjetESTACADC2025ContrledunEssaimdeDronesUIFigure);
            app.FlotteTab = uitab(app.TabGroup);
            app.FlotteTab.Title = 'Flotte';
            app.UIFlotteTable = uitable(app.FlotteTab);
            app.UIFlotteTable.ColumnName = {'N°'; 'Nom'; 'Coordonnées'; 'Cible'; 'Statut'; 'Phase'; 'Type'; 'Modèle'; 'Autonomie Nominale (min)'; 'Autonomie Restante (%)'; 'Recharge Nominale (min)'; 'Recharge Restante (min)'};
            app.UIFlotteTable.RowName = {};
            app.AjoutDroneButton = uibutton(app.FlotteTab, 'push');
            app.AjoutDroneButton.ButtonPushedFcn = createCallbackFcn(app, @AjoutDroneButtonPushed, true);
            app.AjoutDroneButton.Text = 'Ajout';
            app.ModificationDroneButton = uibutton(app.FlotteTab, 'push');
            app.ModificationDroneButton.ButtonPushedFcn = createCallbackFcn(app, @ModificationDroneButtonPushed, true);
            app.ModificationDroneButton.Text = 'Modification';
            app.SuppressionDroneButton = uibutton(app.FlotteTab, 'push');
            app.SuppressionDroneButton.ButtonPushedFcn = createCallbackFcn(app, @SuppressionDroneButtonPushed, true);
            app.SuppressionDroneButton.Text = 'Suppression';
            app.CiblesTab = uitab(app.TabGroup);
            app.CiblesTab.Title = 'Cibles';
            app.UICibleTable = uitable(app.CiblesTab);
            app.UICibleTable.ColumnName = {'N°'; 'Nom'; 'Coordonnées'; 'Statut'; 'Flotte Allouée'; 'Score d''Observabilité'};
            app.UICibleTable.RowName = {};
            app.AjoutCibleButton = uibutton(app.CiblesTab, 'push');
            app.AjoutCibleButton.ButtonPushedFcn = createCallbackFcn(app, @AjoutCibleButtonPushed, true);
            app.AjoutCibleButton.Text = 'Ajout';
            app.ModificationCibleButton = uibutton(app.CiblesTab, 'push');
            app.ModificationCibleButton.ButtonPushedFcn = createCallbackFcn(app, @ModificationCibleButtonPushed, true);
            app.ModificationCibleButton.Text = 'Modification';
            app.SuppressionCibleButton = uibutton(app.CiblesTab, 'push');
            app.SuppressionCibleButton.ButtonPushedFcn = createCallbackFcn(app, @SuppressionCibleButtonPushed, true);
            app.SuppressionCibleButton.Text = 'Suppression';
            app.TargetsPanel = uipanel(app.CiblesTab);
            app.TargetsPanel.Title = 'Targets';
            app.GridLayout = uigridlayout(app.TargetsPanel);
            app.XLabel = uilabel(app.GridLayout);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.GridLayout);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.GridLayout);
            app.ZLabel.Text = 'Z';
            app.TargetnameLabel = uilabel(app.GridLayout);
            app.TargetnameLabel.Text = 'Target name';
            app.TargetDropDown = uidropdown(app.GridLayout);
            app.TargetDropDown.ValueChangedFcn = createCallbackFcn(app, @TargetDropDownValueChanged, true);
            app.EditLabel = uilabel(app.GridLayout);
            app.EditLabel.Text = 'Edit ';
            app.EditTargetFieldX = uieditfield(app.GridLayout, 'text');
            app.EditTargetFieldX.ValueChangedFcn = createCallbackFcn(app, @EditTargetFieldXValueChanged, true);
            app.SliderLabel = uilabel(app.GridLayout);
            app.SliderLabel.Text = 'Slider';
            app.TargetSliderX = uislider(app.GridLayout);
            app.TargetSliderX.ValueChangedFcn = createCallbackFcn(app, @TargetSliderXValueChanged, true);
            app.EditLabel_2 = uilabel(app.GridLayout);
            app.EditLabel_2.Text = 'Edit ';
            app.EditTargetFieldY = uieditfield(app.GridLayout, 'text');
            app.EditTargetFieldY.ValueChangedFcn = createCallbackFcn(app, @EditTargetFieldYValueChanged, true);
            app.Slider_2Label = uilabel(app.GridLayout);
            app.Slider_2Label.Text = 'Slider';
            app.TargetSliderY = uislider(app.GridLayout);
            app.TargetSliderY.ValueChangedFcn = createCallbackFcn(app, @TargetSliderYValueChanged, true);
            app.EditLabel_3 = uilabel(app.GridLayout);
            app.EditLabel_3.Text = 'Edit ';
            app.EditTargetFieldZ = uieditfield(app.GridLayout, 'text');
            app.EditTargetFieldZ.ValueChangedFcn = createCallbackFcn(app, @EditTargetFieldZValueChanged, true);
            app.Slider_3Label = uilabel(app.GridLayout);
            app.Slider_3Label.Text = 'Slider';
            app.TargetSliderZ = uislider(app.GridLayout);
            app.TargetSliderZ.ValueChangedFcn = createCallbackFcn(app, @TargetSliderZValueChanged, true);
            app.ZonesTab = uitab(app.TabGroup);
            app.ZonesTab.Title = 'Zones';
            app.UIZoneTable = uitable(app.ZonesTab);
            app.UIZoneTable.ColumnName = {'N°'; 'Nom'; 'Type'; 'Coordonnées'; 'Taille'; 'Orientation'; 'Statut'; 'Temps de Violation'};
            app.UIZoneTable.RowName = {};
            app.AjoutZoneButton = uibutton(app.ZonesTab, 'push');
            app.AjoutZoneButton.ButtonPushedFcn = createCallbackFcn(app, @AjoutZoneButtonPushed, true);
            app.AjoutZoneButton.Text = 'Ajout';
            app.ModificationZoneButton = uibutton(app.ZonesTab, 'push');
            app.ModificationZoneButton.ButtonPushedFcn = createCallbackFcn(app, @ModificationZoneButtonPushed, true);
            app.ModificationZoneButton.Text = 'Modification';
            app.SuppressionZoneButton = uibutton(app.ZonesTab, 'push');
            app.SuppressionZoneButton.ButtonPushedFcn = createCallbackFcn(app, @SuppressionZoneButtonPushed, true);
            app.SuppressionZoneButton.Text = 'Suppression';
            app.ObstaclesTab = uitab(app.TabGroup);
            app.ObstaclesTab.Title = 'Obstacles';
            app.UIObstacleTable = uitable(app.ObstaclesTab);
            app.UIObstacleTable.ColumnName = {'N°'; 'Coordonnées'; 'Taille'; 'Orientation'; 'Statut'};
            app.UIObstacleTable.RowName = {};
            app.AjoutObstacleButton = uibutton(app.ObstaclesTab, 'push');
            app.AjoutObstacleButton.ButtonPushedFcn = createCallbackFcn(app, @AjoutObstacleButtonPushed, true);
            app.AjoutObstacleButton.Text = 'Ajout';
            app.ModificationObstacleButton = uibutton(app.ObstaclesTab, 'push');
            app.ModificationObstacleButton.ButtonPushedFcn = createCallbackFcn(app, @ModificationObstacleButtonPushed, true);
            app.ModificationObstacleButton.Text = 'Modification';
            app.SuppressionObstacleButton = uibutton(app.ObstaclesTab, 'push');
            app.SuppressionObstacleButton.ButtonPushedFcn = createCallbackFcn(app, @SuppressionObstacleButtonPushed, true);
            app.SuppressionObstacleButton.Text = 'Suppression';
            app.Panel = uipanel(app.IHMProjetESTACADC2025ContrledunEssaimdeDronesUIFigure);
            app.Console = uitextarea(app.Panel);
            app.tempscouleEditField = uieditfield(app.Panel, 'text');
            app.DronesdploysEditFieldLabel = uilabel(app.Panel);
            app.DronesdploysEditFieldLabel.Text = 'Drones déployés';
            app.DronesdploysEditField = uieditfield(app.Panel, 'numeric');
            app.DronesnondploysEditFieldLabel = uilabel(app.Panel);
            app.DronesnondploysEditFieldLabel.Text = 'Drones non déployés';
            app.DronesnondploysEditField = uieditfield(app.Panel, 'numeric');
            app.TempscoulLabel = uilabel(app.Panel);
            app.TempscoulLabel.Text = 'Temps écoulé';
            app.EventLoggingLabel = uilabel(app.Panel);
            app.EventLoggingLabel.Text = 'Log de Simulation';
            app.DronesperdusEditFieldLabel = uilabel(app.Panel);
            app.DronesperdusEditFieldLabel.Text = 'Drones perdus';
            app.DronesperdusEditField = uieditfield(app.Panel, 'numeric');
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PauseButton = uibutton(app.Panel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.DmarrerButton = uibutton(app.Panel, 'push');
            app.DmarrerButton.ButtonPushedFcn = createCallbackFcn(app, @DmarrerButtonPushed, true);
            app.DmarrerButton.Text = 'Démarrer';
            app.Lamp = uilamp(app.Panel);
            app.TranslationGAButton = uibutton(app.Panel, 'state');
            app.TranslationGAButton.ValueChangedFcn = createCallbackFcn(app, @TranslationGAButtonValueChanged, true);
            app.TranslationGAButton.Text = 'Translation GA';
            app.TranslationARButton = uibutton(app.Panel, 'state');
            app.TranslationARButton.ValueChangedFcn = createCallbackFcn(app, @TranslationARButtonValueChanged, true);
            app.TranslationARButton.Text = 'Translation AR';
            app.TranslationDRButton = uibutton(app.Panel, 'state');
            app.TranslationDRButton.ValueChangedFcn = createCallbackFcn(app, @TranslationDRButtonValueChanged, true);
            app.TranslationDRButton.Text = 'Translation DR';
            app.TranslationAVButton = uibutton(app.Panel, 'state');
            app.TranslationAVButton.ValueChangedFcn = createCallbackFcn(app, @TranslationAVButtonValueChanged, true);
            app.TranslationAVButton.Text = 'Translation AV';
            % Show the figure after all components are created
            app.IHMProjetESTACADC2025ContrledunEssaimdeDronesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IHM_FINAL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IHMProjetESTACADC2025ContrledunEssaimdeDronesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IHMProjetESTACADC2025ContrledunEssaimdeDronesUIFigure)
        end
    end
end