classdef AppIHM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IHMSwarmManagerUIFigure        matlab.ui.Figure
        DviewMenu                      matlab.ui.container.Menu
        InfoMenu                       matlab.ui.container.Menu
        Menu                           matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
        Panel                          matlab.ui.container.Panel
        PauseButton                    matlab.ui.control.Button
        DronesperdusEditField_2        matlab.ui.control.NumericEditField
        DronesperdusEditField_2Label   matlab.ui.control.Label
        Console                        matlab.ui.control.TextArea
        EventLoggingLabel              matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        tempscouleEditField            matlab.ui.control.EditField
        TempscoulLabel                 matlab.ui.control.Label
        DronesnondploysEditField       matlab.ui.control.NumericEditField
        DronesnondploysEditFieldLabel  matlab.ui.control.Label
        DronesdploysEditField          matlab.ui.control.NumericEditField
        DronesdploysEditFieldLabel     matlab.ui.control.Label
        dtSlider                       matlab.ui.control.Slider
        dtSliderLabel                  matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        StartButton                    matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        ObservationTab                 matlab.ui.container.Tab
        FlotteTab                      matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        ZonesTab                       matlab.ui.container.Tab
        UITable_2                      matlab.ui.control.Table
        CiblesTab                      matlab.ui.container.Tab
        UITable_3                      matlab.ui.control.Table
        ComportementTab                matlab.ui.container.Tab
        UIAxes3D                       matlab.ui.control.UIAxes
        UIAxes3D_2                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        backend;
        IsRunning = false;
        
        %graphique 3D
        targets;
        heads;
        quiver_handles;
        nb_donnees_courbe; %2D
        targetColors;
        droneViewOn = false;

        %temps
        simu_duration;
        dt;
        time_list = []
        current_time;

        %dimension environnement
        dims;

        %graphiques 2D
        avg_pos_history = [];    % Historique de la norme des positions
        avg_pos_x_history = [];  % Historique des positions moyennes en X
        avg_pos_y_history = [];  
        avg_pos_z_history = [];  
        avg_dist_drone_to_drone_history = [];
        avg_dist_to_target_history;

    end
    
    methods (Access = private)
        
        function update(app)
            tic_var = tic;

            app.backend.update();
            % Pause optionnelle pour contrôler la vitesse de visualisation
            pause(0.001);  % Ajuster ou supprimer selon les besoins
            %drawnow limitrate;
            
            app.current_time = app.current_time + app.dt;
            app.time_list(end+1) = app.current_time;
            %app.tempsrestantEditField.Value = num2str(app.tempsrestantEditField.Value - app.dt);
            %app.tempscouleEditField.Value = num2str(app.tempscouleEditField.Value + app.dt);
            
            app.updatePlot();
            app.update2DPlot();

            app.updateFields(tic_var);
        end
        
        function RunSimulation(app)
            while app.IsRunning
                app.update();
                drawnow;
            end

        end
        
        function Init3DPlot(app)
            %thème sombre
            %app.IHMSwarmManagerUIFigure.Color = [0.15 0.15 0.15];
            %set(app.UIAxes3D, 'Color', [0.15 0.15 0.15]);

            app.UIAxes3D.XLim = [app.dims(1,1), app.dims(1,2)];
            app.UIAxes3D.YLim = [app.dims(2,1), app.dims(2,2)];
            app.UIAxes3D.ZLim = [app.dims(3,1), app.dims(3,2)];

            xlabel(app.UIAxes3D, 'X');  
            ylabel(app.UIAxes3D, 'Y');  
            zlabel(app.UIAxes3D, 'Altitude (Z)'); 
            title(app.UIAxes3D, 'Rendu de Simulation en Temps Réel'); 
            
            hold(app.UIAxes3D, 'on');

            fill3(app.UIAxes3D, app.backend.env.GroundCoordinates.x, app.backend.env.GroundCoordinates.y, app.backend.env.GroundCoordinates.z, ...
                [0.5 0.5 0.5], 'FaceAlpha', 0.3); % Couleur grise avec transparence

            %Affichage des zones (obstacles)
            for i = 1:length(app.backend.env.ZonesList)
                zone = app.backend.env.ZonesList{i};
        
                % Déterminer la couleur selon la catégorie de la zone
                switch zone.Category
                    case 'A'
                        zoneColor = 'g'; % Zones autorisées en vert
                    case 'P'
                        zoneColor = 'r'; % Zones prohibées en rouge
                    case 'M'
                        zoneColor = 'b'; % Zones de manœuvre en bleu
                    otherwise
                        zoneColor = 'y'; % Autres zones en jaune
                end
        
                % Créer la forme de la zone avec le type, la couleur, la position et les dimensions spécifiées
                create_shape(zone.Type, zoneColor, zone.CenterPosition, zone.Dimensions, zone.TiltAngle, app.UIAxes3D);
            end
            
            %Affichage des drones et des targets
            targets = app.backend.swarm.target_list;
            app.targets = gobjects(1, size(targets,1));
            for k = 1:size(targets,1)
                app.targets(k)=scatter3(app.UIAxes3D, targets(k,1), targets(k,2), targets(k,3), 30, 'filled', ...
                    'MarkerFaceColor', app.targetColors(k, :), 'DisplayName', ['Target ', num2str(k)]);
            end
        
            legend(app.UIAxes3D, app.targets, 'Location', 'best'); 

            n_drone=size(app.backend.swarm.Drones,2);
            drone_pos=zeros(n_drone,3);
            for i=1:n_drone
                drone_pos(i,:)=app.backend.swarm.AliveDrones{i}.posState;
        
            end
        
            % Initialiser les objets de tracé pour les éléments animés    
            app.heads=gobjects(1,n_drone);
            app.quiver_handles=gobjects(1,n_drone);
        
            for i=1:n_drone
                app.heads(i)=scatter3(app.UIAxes3D, drone_pos(i,1), drone_pos(i,2), drone_pos(i,3), 30, 'filled', 'MarkerFaceColor', 'b', 'HandleVisibility', 'off');
                app.quiver_handles(i)=quiver3(app.UIAxes3D, drone_pos(i,1), drone_pos(i,2), drone_pos(i,3), 0, 0, 0, 'r', 'LineWidth', 1.5, 'MaxHeadSize', 0.5, 'HandleVisibility', 'off');
            end
            
            % !! à corriger !! (vue depuis un drone)
            if ~app.droneViewOn
                campos(app.UIAxes3D, [0, -0, 75]);
                app.UIAxes3D.View = [45, 30];
            else
                list = app.backend.swarm.AliveDrones
                drone = list(randi([1, length(list)]));
                campos(app.UIAxes3D, [drone.posState(1), drone.posState(2), drone.posState(3)]);

            end
        end

        function Init2DPlot(app)
            xlabel(app.UIAxesDistToTargets, 'Time Steps');
            ylabel(app.UIAxesDistToTargets, 'Avg Dist');
            title(app.UIAxesDistToTargets, 'Average distance swarm/targets');
            legend(app.UIAxesDistToTargets, 'show');
            grid(app.UIAxesDistToTargets, 'on');

            xlabel(app.UIAxesBetwDrones, 'Time Steps');
            ylabel(app.UIAxesBetwDrones, 'Avg Dist');
            title(app.UIAxesBetwDrones, 'Average distance drone/drone');
            grid(app.UIAxesBetwDrones, 'on');
        end

        function updatePlot(app)
            hold(app.UIAxes3D, 'on');
            n_drone=size(app.backend.swarm.Drones,2);
            drone_pos=zeros(n_drone,3);
            drone_speed=zeros(n_drone,3);
            for i=1:n_drone
                drone = app.backend.swarm.Drones{i};
                drone_pos(i,:)=drone.posState;
                drone_speed(i,:)=drone.speedState;
                if drone.IsAlive
                    set(app.heads(i), 'XData', drone_pos(i,1), 'YData', drone_pos(i,2), 'ZData', drone_pos(i,3));
                    set(app.quiver_handles(i), 'XData', drone_pos(i,1), 'YData', drone_pos(i,2), 'ZData', drone_pos(i,3), ...
                     'UData', drone_speed(i,1), 'VData', drone_speed(i,2), 'WData', drone_speed(i,3));
                else
                    set(app.heads(i), 'XData', drone_pos(i,1), 'YData', drone_pos(i,2), 'ZData', drone_pos(i,3), 'MarkerFaceColor', [0 0 0]);
                end
            end
            
        end

        function updateTargetPos(app)
            targets = app.backend.swarm.target_list;
            for k = 1:size(targets, 1)
                set(app.targets(k), 'XData', targets(k, 1), 'YData', targets(k, 2), 'ZData', targets(k, 3));
            end
            
        end
        
        function update2DPlot(app)
            if length(app.time_list) > app.nb_donnees_courbe
                app.time_list(1) = [];
                app.vg_dist_drone_to_drone_history(1) = [];
                for i = 1:numel(app.avg_dist_to_target_history)
                    if ~isempty(app.avg_dist_to_target_history{i})
                        app.avg_dist_to_target_history{i}(1) = [];  % Supprime le premier élément
                    end
                end
            end

            % calculs et affichage distance moyenne à chaque cible

            drones_data = app.backend.swarm.AliveDrones;
            targets_data = app.backend.swarm.target_list;
            avg_pos_x = 0;
            avg_pos_y = 0;
            avg_pos_z = 0;
            for i = 1: length(drones_data)

                avg_pos_x = avg_pos_x + drones_data{i}.posState(1);
                avg_pos_y = avg_pos_y + drones_data{i}.posState(2);
                avg_pos_z = avg_pos_z + drones_data{i}.posState(3);
            end

            avg_pos_x = avg_pos_x /length(drones_data);
            avg_pos_y = avg_pos_y /length(drones_data);
            avg_pos_z = avg_pos_z /length(drones_data);

            avg_pos = sqrt(avg_pos_x^2 + avg_pos_y^2 + avg_pos_z^2);
            app.avg_pos_history(end+1) = avg_pos;
            app.avg_pos_x_history(end+1) = avg_pos_x;
            app.avg_pos_y_history(end+1) = avg_pos_y;
            app.avg_pos_z_history(end+1) = avg_pos_z;
            
            hold(app.UIAxesDistToTargets, 'on');
            cla(app.UIAxesDistToTargets); % Nettoyage du tracé précédent 

            for i = 1: size(targets_data, 1)
                x = targets_data(i, 1);
                y = targets_data(i, 2);
                z = targets_data(i, 3);

                dist_to_target = sqrt((x - avg_pos_x)^2 + (y - avg_pos_y)^2 + (z - avg_pos_z)^2);
                app.avg_dist_to_target_history{i} = [app.avg_dist_to_target_history{i}, dist_to_target];
                
            end
            
            for i = 1:numel(app.avg_dist_to_target_history)
                data = app.avg_dist_to_target_history{i};  % Extraire les distances de la cellule i
                
                if ~isempty(data)  % Vérifier que la cellule contient des données
                    plot(app.UIAxesDistToTargets, app.time_list, data, '-', 'LineWidth', 1, ...
                        'DisplayName', ['Target ', num2str(i)], 'MarkerFaceColor', app.targetColors(i, :));
                end
            end
            
            % calculs et affichage distances moyennes entre drones
            
            avg_dist = 0;
            nb = length(drones_data);
            for i = 1: nb
                for k = i+1: nb
                    avg_dist = avg_dist + sqrt((drones_data{i}.posState(1) - drones_data{k}.posState(1))^2 + (drones_data{i}.posState(2) - drones_data{k}.posState(2))^2 + (drones_data{i}.posState(3) - drones_data{k}.posState(3))^2);
                end
            end
            avg_dist = avg_dist*2/(nb*(nb-1));
            app.avg_dist_drone_to_drone_history(end+1) = avg_dist;

            plot(app.UIAxesBetwDrones, app.time_list, app.avg_dist_drone_to_drone_history, '-', 'LineWidth', 1);

            drawnow limitrate;
        end


        function InitTargetDropDown(app)
            targetNames = {};
            for i = 1:size(app.backend.targetList, 1)
                targetNames{end+1} = [num2str(i)];
            end
            app.TargetDropDown.Items = targetNames;
            %app.TargetDropdown.Value = '1';
        end

        function InitSlider(app)
            %app.dtSlider.Limits = app.backend.env.GroundDimensions;
            app.dtSlider.Limits = [-200 200];
            app.onDropDownUpdate;
        end
        
        
        function updateFields(app, tic_var)
            app.tempsrestantEditField.Value = sprintf('%.1f s', app.simu_duration - app.current_time);
            app.tempscouleEditField.Value = sprintf('%.1f s', app.current_time);
            fps = 1/toc(tic_var);
            app.FPSEditField.Value = fps;
        end
        
        function onDropDownUpdate(app)
            nb_drone = str2num(app.TargetDropDown.Value);
            index_coor = find(strcmp(app.CoorDropDown.Items, app.CoorDropDown.Value));
            value = app.backend.swarm.target_list(nb_drone, index_coor);
            
            app.dtSlider.Limits = [app.dims(index_coor,1), app.dims(index_coor,2)]
            
            app.dtSlider.Value = value;
            app.DronePosField.Value = value;
        end
        
    end
    
    methods (Access = public)
        
        function OnDronesCollision(app, ID1, ID2)
            disp(['collision entre les drones ' num2str(ID1) ' et ' num2str(ID2)])
            % Création du message à afficher
            message = sprintf('Collision entre drone %d et drone %d', ID1, ID2);     
            currentMessages = app.Console.Value;  
            if isempty(currentMessages{1})  
                currentMessages = currentMessages(2:end);  
            end
            currentMessages{end+1} = message;  
            app.Console.Value = currentMessages;

            delete(app.quiver_handles(ID1));
            delete(app.quiver_handles(ID2));

        end

        function OnObstacleCollision(obj, i, k)

        end
        
        function OnDronesNbUpdate(app)
            app.DronesdploysEditField.Value = length(app.backend.swarm.AliveDrones);
            app.DronesnondploysEditField.Value = length(app.backend.swarm.DeadDrones);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.backend = AppBackend();
            app.backend.Init(app);

            settings = app.backend.settings;

            app.simu_duration = settings.temps;
            app.dt = settings.dt;
            app.current_time =0.0;

            app.dims = app.backend.settings.dims;
            app.nb_donnees_courbe = app.backend.settings.nb_donnees_courbe;

            app.DronesdploysEditField.Value = length(app.backend.swarm.AliveDrones);
            app.DronesnondploysEditField.Value = length(app.backend.swarm.DeadDrones);
            
            app.targetColors = lines(size(app.backend.swarm.target_list, 1));

            app.avg_dist_to_target_history = cell(size(app.backend.swarm.target_list, 1), 1);

            app.tempsrestantEditField.Value = sprintf('%.1f s', app.simu_duration);
            app.tempscouleEditField.Value = '0 s';

            app.Init2DPlot();
            app.Init3DPlot();
            app.InitTargetDropDown();
            app.InitSlider();

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            if ~app.IsRunning
                app.IsRunning = true;
                app.StartButton.Text = 'Pause';
                app.Lamp.Color = [0.00,1.00,0.00];
                RunSimulation(app);
            else
                app.IsRunning = false;
                app.StartButton.Text = 'Run';
                app.Lamp.Color = [1.00,0.00,0.00];
            end


        end

        % Callback function: not associated with a component
        function TargetDropDownValueChanged(app, event)
            app.onDropDownUpdate();
            
        end

        % Callback function: not associated with a component
        function CoorDropDownValueChanged(app, event)
            app.onDropDownUpdate();
            
        end

        % Value changed function: dtSlider
        function dtSliderValueChanged(app, event)
            nb_drone = str2num(app.TargetDropDown.Value);
            index_coor = find(strcmp(app.CoorDropDown.Items, app.CoorDropDown.Value));
            new_value = app.dtSlider.Value;

            new_target_list = app.backend.swarm.target_list;
            new_target_list(nb_drone, index_coor) = new_value;
            
            app.DronePosField.Value = new_value;

            app.backend.swarm.update_target(new_target_list);
            app.updateTargetPos();
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.droneViewOn = ~app.droneViewOn;
            if app.droneViewOn
                app.ResetButton.Text = "Vue drone";
            else
                app.ResetButton.Text = "Vue spectateur"
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IHMSwarmManagerUIFigure = uifigure('Visible', 'off');
            colormap(app.IHMSwarmManagerUIFigure, 'jet');
            app.IHMSwarmManagerUIFigure.Name = 'IHM - Swarm Manager';
            app.DviewMenu = uimenu(app.IHMSwarmManagerUIFigure);
            app.DviewMenu.Text = '3Dview';
            app.InfoMenu = uimenu(app.IHMSwarmManagerUIFigure);
            app.InfoMenu.Text = 'Info';
            app.Menu = uimenu(app.InfoMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.InfoMenu);
            app.Menu2.Text = 'Menu2';
            app.UIAxes3D_2 = uiaxes(app.IHMSwarmManagerUIFigure);
            title(app.UIAxes3D_2, 'Affichage 2D')
            xlabel(app.UIAxes3D_2, 'X')
            ylabel(app.UIAxes3D_2, 'Y')
            app.UIAxes3D_2.ZTick = [];
            app.UIAxes3D = uiaxes(app.IHMSwarmManagerUIFigure);
            title(app.UIAxes3D, 'Affichage 3D')
            xlabel(app.UIAxes3D, 'X')
            ylabel(app.UIAxes3D, 'Y')
            zlabel(app.UIAxes3D, 'Z')
            app.TabGroup = uitabgroup(app.IHMSwarmManagerUIFigure);
            app.ObservationTab = uitab(app.TabGroup);
            app.ObservationTab.Title = 'Observation';
            app.FlotteTab = uitab(app.TabGroup);
            app.FlotteTab.Title = 'Flotte';
            app.UITable = uitable(app.FlotteTab);
            app.UITable.ColumnName = {'Liste'; 'Coordonnées'; 'Type'; 'Statut'; 'Phase de Mission'; 'Cible Allouée'};
            app.UITable.RowName = {};
            app.ZonesTab = uitab(app.TabGroup);
            app.ZonesTab.Title = 'Zones';
            app.UITable_2 = uitable(app.ZonesTab);
            app.UITable_2.ColumnName = {'Liste'; 'Coordonnées'; 'Type'; 'Statut'; 'Temps de Violation'};
            app.UITable_2.RowName = {};
            app.CiblesTab = uitab(app.TabGroup);
            app.CiblesTab.Title = 'Cibles';
            app.UITable_3 = uitable(app.CiblesTab);
            app.UITable_3.ColumnName = {'Liste'; 'Coordonnées'; 'Flotte Allouée'; 'Statut'; 'Score d''Observabilité'};
            app.UITable_3.RowName = {};
            app.ComportementTab = uitab(app.TabGroup);
            app.ComportementTab.Title = 'Comportement';
            app.Panel = uipanel(app.IHMSwarmManagerUIFigure);
            app.StartButton = uibutton(app.Panel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Lamp = uilamp(app.Panel);
            app.dtSliderLabel = uilabel(app.Panel);
            app.dtSliderLabel.Text = 'dt';
            app.dtSlider = uislider(app.Panel);
            app.dtSlider.Limits = [0.03 10];
            app.dtSlider.ValueChangedFcn = createCallbackFcn(app, @dtSliderValueChanged, true);
            app.dtSlider.Value = 0.03;
            app.DronesdploysEditFieldLabel = uilabel(app.Panel);
            app.DronesdploysEditFieldLabel.Text = 'Drones déployés';
            app.DronesdploysEditField = uieditfield(app.Panel, 'numeric');
            app.DronesnondploysEditFieldLabel = uilabel(app.Panel);
            app.DronesnondploysEditFieldLabel.Text = 'Drones non déployés';
            app.DronesnondploysEditField = uieditfield(app.Panel, 'numeric');
            app.TempscoulLabel = uilabel(app.Panel);
            app.TempscoulLabel.Text = 'Temps écoulé';
            app.tempscouleEditField = uieditfield(app.Panel, 'text');
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.EventLoggingLabel = uilabel(app.Panel);
            app.EventLoggingLabel.Text = 'Event Logging';
            app.Console = uitextarea(app.Panel);
            app.DronesperdusEditField_2Label = uilabel(app.Panel);
            app.DronesperdusEditField_2Label.Text = 'Drones perdus';
            app.DronesperdusEditField_2 = uieditfield(app.Panel, 'numeric');
            app.PauseButton = uibutton(app.Panel, 'push');
            app.PauseButton.Text = 'Pause';
            % Show the figure after all components are created
            app.IHMSwarmManagerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppIHM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IHMSwarmManagerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IHMSwarmManagerUIFigure)
        end
    end
end